classdef FFCAnalysis_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel_14                    matlab.ui.container.Panel
        ImagedisplayPanel           matlab.ui.container.Panel
        Panel_10                    matlab.ui.container.Panel
        FieldmTLabel                matlab.ui.control.Label
        EvolutiontimemsLabel        matlab.ui.control.Label
        TimeDropDown                matlab.ui.control.DropDown
        FieldDropDown               matlab.ui.control.DropDown
        minvalue                    matlab.ui.control.NumericEditField
        minSliderLabel              matlab.ui.control.Label
        minSlider                   matlab.ui.control.Slider
        WindowLabel                 matlab.ui.control.Label
        maxSliderLabel              matlab.ui.control.Label
        maxSlider                   matlab.ui.control.Slider
        maxvalue                    matlab.ui.control.NumericEditField
        ResetwindowButton           matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        Panel_11                    matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        DrawmaskTab                 matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        Panel_12                    matlab.ui.container.Panel
        noofregionsEditFieldLabel   matlab.ui.control.Label
        noofregionsEditField        matlab.ui.control.NumericEditField
        DrawButton                  matlab.ui.control.Button
        maskname_2                  matlab.ui.control.EditField
        SaveButton_2                matlab.ui.control.Button
        LoadmaskTab                 matlab.ui.container.Tab
        UIAxes_10                   matlab.ui.control.UIAxes
        Panel_13                    matlab.ui.container.Panel
        LoadButton_2                matlab.ui.control.Button
        RemoveoutliersTab           matlab.ui.container.Tab
        UITable_3                   matlab.ui.control.Table
        UpdateoutlierremovalButton  matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        ROIdrawTab                  matlab.ui.container.Tab
        TabGroup4_2                 matlab.ui.container.TabGroup
        ViewregionTab               matlab.ui.container.Tab
        UIAxes_ROI                  matlab.ui.control.UIAxes
        ROIList2                    matlab.ui.control.DropDown
        savefig_CB_ROI              matlab.ui.control.CheckBox
        SavefigureLabel_6           matlab.ui.control.Label
        figname_ROI                 matlab.ui.control.EditField
        UpdateButton_6              matlab.ui.control.Button
        NMRDFittingPanel_2          matlab.ui.container.Panel
        Label_12                    matlab.ui.control.Label
        Panel_18                    matlab.ui.container.Panel
        Label_13                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        Label_15                    matlab.ui.control.Label
        Label_16                    matlab.ui.control.Label
        Label_17                    matlab.ui.control.Label
        NameLabel_2                 matlab.ui.control.Label
        FitLabel_2                  matlab.ui.control.Label
        ROI1_name                   matlab.ui.control.EditField
        ROI2_name                   matlab.ui.control.EditField
        ROI3_name                   matlab.ui.control.EditField
        ROI4_name                   matlab.ui.control.EditField
        ROI5_name                   matlab.ui.control.EditField
        CB1_2                       matlab.ui.control.CheckBox
        CB2_2                       matlab.ui.control.CheckBox
        CB3_2                       matlab.ui.control.CheckBox
        CB4_2                       matlab.ui.control.CheckBox
        CB5_2                       matlab.ui.control.CheckBox
        IndxLabel_3                 matlab.ui.control.Label
        DrawLoadLabel               matlab.ui.control.Label
        DrawButton_1                matlab.ui.control.Button
        DrawButton_2                matlab.ui.control.Button
        DrawButton_3                matlab.ui.control.Button
        DrawButton_4                matlab.ui.control.Button
        DrawButton_5                matlab.ui.control.Button
        Switch_2                    matlab.ui.control.Switch
        LoadButton_ROI1             matlab.ui.control.Button
        LoadButton_ROI2             matlab.ui.control.Button
        LoadButton_ROI3             matlab.ui.control.Button
        LoadButton_ROI4             matlab.ui.control.Button
        LoadButton_ROI5             matlab.ui.control.Button
        Switch_3                    matlab.ui.control.Switch
        circlesize                  matlab.ui.control.NumericEditField
        CircleradiusLabel           matlab.ui.control.Label
        voxelsLabel_3               matlab.ui.control.Label
        median_mean                 matlab.ui.control.Switch
        ROIaverageasLabel           matlab.ui.control.Label
        RunfitButton_2              matlab.ui.control.Button
        RunningLamp_3Label          matlab.ui.control.Label
        RunningLamp_3               matlab.ui.control.Lamp
        Panel_20                    matlab.ui.container.Panel
        UITable_2                   matlab.ui.control.Table
        R1initialguessLabel_2       matlab.ui.control.Label
        noise_2                     matlab.ui.control.NumericEditField
        NoisefactorLabel_2          matlab.ui.control.Label
        ofsignalLabel_2             matlab.ui.control.Label
        Label_23                    matlab.ui.control.Label
        Label_24                    matlab.ui.control.Label
        Label_25                    matlab.ui.control.Label
        Label_26                    matlab.ui.control.Label
        Label_27                    matlab.ui.control.Label
        IndxLabel_5                 matlab.ui.control.Label
        GuessLabel                  matlab.ui.control.Label
        Set1                        matlab.ui.control.Button
        Set2                        matlab.ui.control.Button
        Set3                        matlab.ui.control.Button
        Set4                        matlab.ui.control.Button
        Set5                        matlab.ui.control.Button
        Lamp1                       matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        Lamp5                       matlab.ui.control.Lamp
        ResetButton                 matlab.ui.control.Button
        TabGroup5_2                 matlab.ui.container.TabGroup
        NMRDTab_2                   matlab.ui.container.Tab
        UIAxes_11                   matlab.ui.control.UIAxes
        TabGroup7_2                 matlab.ui.container.TabGroup
        ROITab                      matlab.ui.container.Tab
        IndxLabel_4                 matlab.ui.control.Label
        SelectLabel_2               matlab.ui.control.Label
        A1_2                        matlab.ui.control.CheckBox
        A2_2                        matlab.ui.control.CheckBox
        A3_2                        matlab.ui.control.CheckBox
        A4_2                        matlab.ui.control.CheckBox
        A5_2                        matlab.ui.control.CheckBox
        Label_18                    matlab.ui.control.Label
        Label_19                    matlab.ui.control.Label
        Label_20                    matlab.ui.control.Label
        Label_21                    matlab.ui.control.Label
        Label_22                    matlab.ui.control.Label
        LogscalexLabel_2            matlab.ui.control.Label
        LogscaleyLabel_2            matlab.ui.control.Label
        xlog_CB_2                   matlab.ui.control.CheckBox
        ylog_CB_2                   matlab.ui.control.CheckBox
        xminLabel_3                 matlab.ui.control.Label
        xmaxLabel_3                 matlab.ui.control.Label
        yminLabel_3                 matlab.ui.control.Label
        ymaxLabel_3                 matlab.ui.control.Label
        ErrorbarsLabel_2            matlab.ui.control.Label
        errorbar_CB_2               matlab.ui.control.CheckBox
        AutoscaleLabel_3            matlab.ui.control.Label
        autoscale_CB_3              matlab.ui.control.CheckBox
        xmin_3                      matlab.ui.control.NumericEditField
        xmax_3                      matlab.ui.control.NumericEditField
        ymin_3                      matlab.ui.control.NumericEditField
        ymax_3                      matlab.ui.control.NumericEditField
        figname_7                   matlab.ui.control.EditField
        mTLabel_2                   matlab.ui.control.Label
        sLabel_4                    matlab.ui.control.Label
        exportfigureonlyLabel_2     matlab.ui.control.Label
        SavefigureLabel_7           matlab.ui.control.Label
        savefig_CB_7                matlab.ui.control.CheckBox
        UpdateButton_9              matlab.ui.control.Button
        FitTab_2                    matlab.ui.container.Tab
        ROIlist                     matlab.ui.control.DropDown
        xminLabel_4                 matlab.ui.control.Label
        xmaxLabel_4                 matlab.ui.control.Label
        yminLabel_4                 matlab.ui.control.Label
        ymaxLabel_4                 matlab.ui.control.Label
        AutoscaleLabel_4            matlab.ui.control.Label
        autoscale_CB_4              matlab.ui.control.CheckBox
        xmin_4                      matlab.ui.control.NumericEditField
        xmax_4                      matlab.ui.control.NumericEditField
        ymin_4                      matlab.ui.control.NumericEditField
        ymax_4                      matlab.ui.control.NumericEditField
        sLabel_5                    matlab.ui.control.Label
        UpdateButton_8              matlab.ui.control.Button
        figname_8                   matlab.ui.control.EditField
        SavefigureLabel_8           matlab.ui.control.Label
        savefig_CB_8                matlab.ui.control.CheckBox
        ROILabel                    matlab.ui.control.Label
        rsquaredLabel_2             matlab.ui.control.Label
        r2_2                        matlab.ui.control.NumericEditField
        Panel_19                    matlab.ui.container.Panel
        Panel_16                    matlab.ui.container.Panel
        ROIFitLabel                 matlab.ui.control.Label
        FoldernameLabel_2           matlab.ui.control.Label
        foldername_2                matlab.ui.control.EditField
        ExcelfilenameLabel_2        matlab.ui.control.Label
        excelname_2                 matlab.ui.control.EditField
        fitallclusters_4            matlab.ui.control.Button
        ClusterTab                  matlab.ui.container.Tab
        HierarchicalclusteranalysisPanel  matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        threshold                   matlab.ui.control.NumericEditField
        SmoothingLabel              matlab.ui.control.Label
        Smoothing_CheckBox          matlab.ui.control.CheckBox
        voxelsLabel_2               matlab.ui.control.Label
        SDLabel                     matlab.ui.control.Label
        smoothingkernel             matlab.ui.control.NumericEditField
        ThresholdSliderLabel        matlab.ui.control.Label
        ThresholdSlider             matlab.ui.control.Slider
        ModefilterLabel             matlab.ui.control.Label
        modeCB                      matlab.ui.control.CheckBox
        SizeLabel                   matlab.ui.control.Label
        modefiltersize              matlab.ui.control.NumericEditField
        xbyxvoxelsLabel             matlab.ui.control.Label
        RunclusterButton            matlab.ui.control.Button
        MagnitudeimageLabel         matlab.ui.control.Label
        CompleximageLabel           matlab.ui.control.Label
        magCB                       matlab.ui.control.CheckBox
        compCB                      matlab.ui.control.CheckBox
        TabGroup4                   matlab.ui.container.TabGroup
        ClusterTab_2                matlab.ui.container.Tab
        UIAxes_cluster              matlab.ui.control.UIAxes
        FilteredclusterTab          matlab.ui.container.Tab
        UIAxes_cluster_2            matlab.ui.control.UIAxes
        ViewclusterTab              matlab.ui.container.Tab
        TabGroup8                   matlab.ui.container.TabGroup
        AllTab_2                    matlab.ui.container.Tab
        cluster_option_plot         matlab.ui.control.DropDown
        UpdateButton_2              matlab.ui.control.Button
        figname_2                   matlab.ui.control.EditField
        savefig_CB_2                matlab.ui.control.CheckBox
        SavefigureLabel_2           matlab.ui.control.Label
        DefinedTab_2                matlab.ui.container.Tab
        cluster_option_plot_2       matlab.ui.control.DropDown
        UpdateButton_3              matlab.ui.control.Button
        figname_3                   matlab.ui.control.EditField
        savefig_CB_3                matlab.ui.control.CheckBox
        SavefigureLabel_3           matlab.ui.control.Label
        UIAxes_cluster_3            matlab.ui.control.UIAxes
        NMRDFittingPanel            matlab.ui.container.Panel
        TabGroup6                   matlab.ui.container.TabGroup
        DefineclustersTab           matlab.ui.container.Tab
        Panel_7                     matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        ClusterIDLabel              matlab.ui.control.Label
        FitLabel                    matlab.ui.control.Label
        Cluster1_name               matlab.ui.control.EditField
        Cluster2_name               matlab.ui.control.EditField
        Cluster3_name               matlab.ui.control.EditField
        Cluster4_name               matlab.ui.control.EditField
        Cluster5_name               matlab.ui.control.EditField
        CB1                         matlab.ui.control.CheckBox
        Cluster1                    matlab.ui.control.EditField
        CB2                         matlab.ui.control.CheckBox
        Cluster2                    matlab.ui.control.EditField
        CB3                         matlab.ui.control.CheckBox
        Cluster3                    matlab.ui.control.EditField
        CB4                         matlab.ui.control.CheckBox
        Cluster4                    matlab.ui.control.EditField
        CB5                         matlab.ui.control.CheckBox
        Cluster5                    matlab.ui.control.EditField
        SearchclustersButton        matlab.ui.control.Button
        IndxLabel                   matlab.ui.control.Label
        c_value                     matlab.ui.control.NumericEditField
        median_mean_2               matlab.ui.control.Switch
        ClusterIDLabel_2            matlab.ui.control.Label
        ROIaverageasLabel_2         matlab.ui.control.Label
        RunfitButton                matlab.ui.control.Button
        RunningLampLabel            matlab.ui.control.Label
        RunningLamp                 matlab.ui.control.Lamp
        AllclustersTab              matlab.ui.container.Tab
        MinimumclustersizeLabel     matlab.ui.control.Label
        minclustersize              matlab.ui.control.NumericEditField
        voxelsLabel                 matlab.ui.control.Label
        RunningLamp_2Label          matlab.ui.control.Label
        RunningLamp_2               matlab.ui.control.Lamp
        fitallclusters              matlab.ui.control.Button
        median_mean_3               matlab.ui.control.Switch
        ROIaverageasLabel_3         matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Panel_21                    matlab.ui.container.Panel
        UITable_4                   matlab.ui.control.Table
        R1initialguessLabel_3       matlab.ui.control.Label
        noise_3                     matlab.ui.control.NumericEditField
        NoisefactorLabel_3          matlab.ui.control.Label
        ofsignalLabel_3             matlab.ui.control.Label
        Label_28                    matlab.ui.control.Label
        Label_29                    matlab.ui.control.Label
        Label_30                    matlab.ui.control.Label
        Label_31                    matlab.ui.control.Label
        Label_32                    matlab.ui.control.Label
        IndxLabel_6                 matlab.ui.control.Label
        GuessLabel_2                matlab.ui.control.Label
        Set1_2                      matlab.ui.control.Button
        Set2_2                      matlab.ui.control.Button
        Set3_2                      matlab.ui.control.Button
        Set4_2                      matlab.ui.control.Button
        Set5_2                      matlab.ui.control.Button
        Lamp1_2                     matlab.ui.control.Lamp
        Lamp2_2                     matlab.ui.control.Lamp
        Lamp3_2                     matlab.ui.control.Lamp
        Lamp4_2                     matlab.ui.control.Lamp
        Lamp5_2                     matlab.ui.control.Lamp
        ResetButton_2               matlab.ui.control.Button
        TabGroup5                   matlab.ui.container.TabGroup
        NMRDTab                     matlab.ui.container.Tab
        UIAxes_7                    matlab.ui.control.UIAxes
        TabGroup7                   matlab.ui.container.TabGroup
        AllTab                      matlab.ui.container.Tab
        MultipleclustersLabel       matlab.ui.control.Label
        multipleclusters_V          matlab.ui.control.EditField
        multipleclusters_CB         matlab.ui.control.CheckBox
        cluster_option              matlab.ui.control.DropDown
        singleclusters_CB           matlab.ui.control.CheckBox
        SingeclusterLabel           matlab.ui.control.Label
        max5Label                   matlab.ui.control.Label
        DefinedTab                  matlab.ui.container.Tab
        IndxLabel_2                 matlab.ui.control.Label
        SelectLabel                 matlab.ui.control.Label
        A1                          matlab.ui.control.CheckBox
        A2                          matlab.ui.control.CheckBox
        A3                          matlab.ui.control.CheckBox
        A4                          matlab.ui.control.CheckBox
        A5                          matlab.ui.control.CheckBox
        Label_6                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        LogscalexLabel              matlab.ui.control.Label
        LogscaleyLabel              matlab.ui.control.Label
        xlog_CB                     matlab.ui.control.CheckBox
        ylog_CB                     matlab.ui.control.CheckBox
        xminLabel                   matlab.ui.control.Label
        xmaxLabel                   matlab.ui.control.Label
        yminLabel                   matlab.ui.control.Label
        ymaxLabel                   matlab.ui.control.Label
        ErrorbarsLabel              matlab.ui.control.Label
        errorbar_CB                 matlab.ui.control.CheckBox
        AutoscaleLabel              matlab.ui.control.Label
        autoscale_CB                matlab.ui.control.CheckBox
        xmin                        matlab.ui.control.NumericEditField
        xmax                        matlab.ui.control.NumericEditField
        ymin                        matlab.ui.control.NumericEditField
        ymax                        matlab.ui.control.NumericEditField
        UpdateButton                matlab.ui.control.Button
        figname                     matlab.ui.control.EditField
        mTLabel                     matlab.ui.control.Label
        sLabel                      matlab.ui.control.Label
        exportfigureonlyLabel       matlab.ui.control.Label
        SavefigureLabel             matlab.ui.control.Label
        savefig_CB                  matlab.ui.control.CheckBox
        FitTab                      matlab.ui.container.Tab
        allfit                      matlab.ui.control.DropDown
        definefit                   matlab.ui.control.DropDown
        xminLabel_2                 matlab.ui.control.Label
        xmaxLabel_2                 matlab.ui.control.Label
        yminLabel_2                 matlab.ui.control.Label
        ymaxLabel_2                 matlab.ui.control.Label
        AutoscaleLabel_2            matlab.ui.control.Label
        autoscale_CB_2              matlab.ui.control.CheckBox
        xmin_2                      matlab.ui.control.NumericEditField
        xmax_2                      matlab.ui.control.NumericEditField
        ymin_2                      matlab.ui.control.NumericEditField
        ymax_2                      matlab.ui.control.NumericEditField
        sLabel_3                    matlab.ui.control.Label
        UpdateButton_4              matlab.ui.control.Button
        figname_4                   matlab.ui.control.EditField
        SavefigureLabel_4           matlab.ui.control.Label
        savefig_CB_4                matlab.ui.control.CheckBox
        ClusterLabel                matlab.ui.control.Label
        allfit_CB                   matlab.ui.control.CheckBox
        definefit_CB                matlab.ui.control.CheckBox
        rsquaredLabel               matlab.ui.control.Label
        r2                          matlab.ui.control.NumericEditField
        Panel_8                     matlab.ui.container.Panel
        Panel_9                     matlab.ui.container.Panel
        AllclusterfitLabel          matlab.ui.control.Label
        all_CB                      matlab.ui.control.CheckBox
        def_CB                      matlab.ui.control.CheckBox
        DefinedclusterfitLabel      matlab.ui.control.Label
        FoldernameLabel             matlab.ui.control.Label
        foldername                  matlab.ui.control.EditField
        excelname                   matlab.ui.control.EditField
        ExcelfilenameLabel          matlab.ui.control.Label
        fitallclusters_2            matlab.ui.control.Button
        Panel_15                    matlab.ui.container.Panel
        LoadfileButton              matlab.ui.control.Button
        matfileexportedfromImageProcessingLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        myStruct
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ffc_object)

            if nargin > 1
            
            b = ffc_object;
            a.inputfile = b;
        
            obj.compleximage = a.inputfile.compleximage;
            obj.magimage = a.inputfile.magimage;
            obj.n_timepoints = a.inputfile.n_timepoints;
            obj.n_fieldpoints = a.inputfile.n_fieldpoints;
            obj.timepoints = a.inputfile.timepoints;
            obj.fieldpoints = a.inputfile.fieldpoints; 
            obj.B0_pol = a.inputfile.param{62, 3};

            app.myStruct = obj;
            app.myStruct.inputfile = a.inputfile;
            
            for ix=1:length(app.myStruct.fieldpoints)
                fielditems{ix} = num2str(app.myStruct.fieldpoints(ix));
            end
                   
            app.FieldDropDown.Items = fielditems;
            
            for ix=1:length(app.myStruct.timepoints(1,:))
                timeitems{ix} = num2str(app.myStruct.timepoints(1,ix));
            end
            
            app.TimeDropDown.Items = timeitems;
            
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,1,1)));
            dim = size(app.myStruct.magimage);
            app.myStruct.dim = dim;
            app.UIAxes.XLim = [0 dim(2)];
            app.UIAxes.YLim = [0 dim(1)];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes,gray)
                       
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.XLim = [0 dim(2)];
            app.UIAxes_2.YLim = [0 dim(1)];
           
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.XLim = [0 dim(2)];
            app.UIAxes_10.YLim = [0 dim(1)];
            
            temp = abs(app.myStruct.compleximage(:,:,1,1,1));
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;        
           
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
            app.threshold.Value = [1.15];
            app.ThresholdSlider.Limits = [app.threshold.Value-0.05,app.threshold.Value+0.05];
            app.ThresholdSlider.Value = [1.15];
       
            nofields = length(app.myStruct.fieldpoints);
            app.UITable_4.Data(:,1) = app.myStruct.fieldpoints;
            app.UITable_4.Data(:,2) = 2*ones(nofields,1);
            app.UITable_2.Data(:,1) = app.myStruct.fieldpoints;
            app.UITable_2.Data(:,2) = 2*ones(nofields,1);
            
            app.RunningLamp.Enable = 0;
            
             t = obj.timepoints; B0 = obj.fieldpoints;
            [~, number_times] = size(t);
             x = t; x = x(:);
             y = repmat(B0,1,number_times); y = y(:);
             
             app.UITable_3.Data(:,1) = y;
             app.UITable_3.Data(:,2) = x;
             app.UITable_3.Data(:,3) = ones(length(x),1);
             
             app.myStruct.outliers = app.UITable_3.Data(:,3);
            
             app.myStruct.R1_guess = NaN([5,nofields]);
             app.myStruct.Noise_guess = NaN(1,5);
             app.myStruct.R1_guess_2 = NaN([5,nofields]);
             app.myStruct.Noise_guess_2 = NaN(1,5);
             
            end
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            no_ROI = app.noofregionsEditField.Value;
            
            data = app.myStruct.magimage;
            dim = size(data);

mask = zeros(dim(1),dim(2));
maskt = zeros(dim(1),dim(2));


figure
imagesc(abs(app.myStruct.compleximage(:,:,1,kt,kf)));
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            caxis([minvaluet, maxvaluet]);
axis image
colormap gray

for tx = 1:no_ROI
maskt(:,:) = roipoly;
mask(:,:) = mask+maskt;
maskt = zeros(dim(1),dim(2));
imagesc(app.UIAxes_2, mask(:,:,1,1,1));
colormap(app.UIAxes_2,gray)
end 

close 

mask(mask>0) = 1;
imagesc(app.UIAxes_2, mask(:,:,1,end,end));
colormap(app.UIAxes_2,gray)
app.myStruct.mask = mask;
                   
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            
            value = app.Switch.Value;
            value = str2double(value);
            
            
            fieldvalue = str2double(app.FieldDropDown.Value);
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            
            for ix=1:length(app.myStruct.timepoints(kf,:))
                timeitems{ix} = num2str(app.myStruct.timepoints(kf,ix));
            end
            app.TimeDropDown.Items = timeitems;
            
            timevalue = str2double(app.TimeDropDown.Value);
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            if value == 1
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,kt,kf)));
            temp = abs(app.myStruct.compleximage(:,:,1,kt,kf));
            end
            
            if value == 0
            imagesc(app.UIAxes, app.myStruct.magimage(:,:,1,kt,kf));
            temp = app.myStruct.magimage(:,:,1,kt,kf);
            end    
            
            
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;  
            
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            
            value = app.Switch.Value;
            value = str2double(value);
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            if value == 1
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,kt,kf)));
            temp = abs(app.myStruct.compleximage(:,:,1,kt,kf));
            end
            
            if value == 0
            imagesc(app.UIAxes, app.myStruct.magimage(:,:,1,kt,kf));
            temp = app.myStruct.magimage(:,:,1,kt,kf);
            end    
            
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;  
           
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
            
        end

        % Value changed function: minSlider
        function minSliderValueChanged(app, event)
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.minvalue.Value = app.minSlider.Value;
            app.maxvalue.Value = app.maxSlider.Value;
            
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
        end

        % Value changed function: maxSlider
        function maxSliderValueChanged(app, event)
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.minvalue.Value = app.minSlider.Value;
            app.maxvalue.Value = app.maxSlider.Value;
            
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
            
        end

        % Value changed function: minvalue
        function minvalueValueChanged(app, event)
            minvaluet = app.minvalue.Value;
            maxvaluet = app.maxvalue.Value;
            app.minSlider.Value = app.minvalue.Value;
            app.maxSlider.Value = app.maxvalue.Value; 
            
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
        end

        % Value changed function: maxvalue
        function maxvalueValueChanged(app, event)
            minvaluet = app.minvalue.Value;
            maxvaluet = app.maxvalue.Value;
            app.minSlider.Value = app.minvalue.Value;
            app.maxSlider.Value = app.maxvalue.Value; 
            
            app.UIAxes.CLim = [minvaluet maxvaluet];            
        end

        % Button pushed function: ResetwindowButton
        function ResetwindowButtonPushed(app, event)
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
                
            temp = abs(app.myStruct.compleximage(:,:,1,kt,kf));
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;       
            
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,kt,kf)));
            minvaluet = app.minvalue.Value;
            maxvaluet = app.maxvalue.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];
        end

        % Button pushed function: RunclusterButton
        function RunclusterButtonPushed(app, event)
            
            if app.Smoothing_CheckBox.Value == 0
                
                s_value = NaN;
                
            end
            
            if app.Smoothing_CheckBox.Value == 1
                
                s_value = app.smoothingkernel.Value;
                
            end
            
            
            if app.modeCB.Value == 0
                
                mode_size = NaN;
                
            end
            
            if app.modeCB.Value == 1
                
                mode_size = app.modefiltersize.Value;
                
            end  
            

        [cluster_out] = FFC_cluster(app.myStruct,app.threshold.Value,app.myStruct.mask,s_value,mode_size,app.compCB.Value,app.myStruct.outliers);
        
%         save('cluster_out','cluster_out')
        
        AT = max(cluster_out.clusterimg_unfilt(:));
        
        cluster_out.AT = AT;
        
        app.myStruct.cluster_out = cluster_out;

            imagesc(app.UIAxes_cluster, cluster_out.clusterimg_unfilt);
            colormap(app.UIAxes_cluster);
            imagesc(app.UIAxes_cluster_2, cluster_out.clusterimg); 
            colormap(app.UIAxes_cluster_2);
            app.UIAxes_cluster.CLim = [0 AT];
            
            app.UIAxes_cluster.XTick = []; app.UIAxes_cluster.YTick = [];
            app.UIAxes_cluster.XLim = [0 app.myStruct.dim(2)]; app.UIAxes_cluster.YLim = [0 app.myStruct.dim(1)];
            
            app.UIAxes_cluster_2.XTick = []; app.UIAxes_cluster_2.YTick = [];
            app.UIAxes_cluster_2.XLim = [0 app.myStruct.dim(2)]; app.UIAxes_cluster_2.YLim = [0 app.myStruct.dim(1)];
            app.UIAxes_cluster_2.CLim = [0 AT];
             
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = round(app.ThresholdSlider.Value,3);
            app.threshold.Value = value;
            app.ThresholdSlider.Value = value;


        end

        % Value changed function: threshold
        function thresholdValueChanged(app, event)
         app.ThresholdSlider.Limits = [app.threshold.Value-0.05,app.threshold.Value+0.05];
         app.ThresholdSlider.Value = app.threshold.Value;
        end

        % Button pushed function: SearchclustersButton
        function SearchclustersButtonPushed(app, event)
          
           
            tempval = double(app.compCB.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            AT = app.myStruct.cluster_out.AT;
            
            imt = app.myStruct.cluster_out.clusterimg;
            
            
            while 1 < 2
                
               close all
            
            figure
            imagesc(imt);
            axis image;
            caxis([0 AT]);
            title('Select a single point here.','FontSize',20);
            xticks([])
            yticks([])
                	
            button = questdlg('Select single point and then press enter. Or press finish to end search.', 'Next Point' , 'Select', 'Finish', 'Select');
    
            if strcmpi(button, 'Finish')
		
            break;
    
            end


            [col,row] = getpts;
            col = round(col);
            row = round(row);
            
            
           figure
            
            imgt = zeros(app.myStruct.dim(1),app.myStruct.dim(2));
            [rowt,colt] = find(imt == imt(row,col));
            
            for vx = 1:length(rowt)
            imgt(rowt(vx),colt(vx)) = 1;
            end
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            imgt(imgt == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,imgt); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(imgt)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            app.c_value.Value = imt(row,col);
      
            title(['Cluster at (row,col) (' num2str(row) ',' num2str(col) ') = ' num2str(imt(row,col))])
            
            
            button = questdlg('Continue to select next point or finish.', 'Next Point' , 'Next', 'Finish', 'Next');
    
            if strcmpi(button, 'Finish')
		
            break;
    
            end


            end


        end

        % Callback function
        function FinishsearchButtonPushed(app, event)
             
          
            close 
            
        end

        % Button pushed function: RunfitButton
        function RunfitButtonPushed(app, event)
            
           B0_pol = app.myStruct.B0_pol./1000;
           t = app.myStruct.timepoints./1000;
           Bevo = app.myStruct.fieldpoints;
           
           AC(1) = app.CB1.Value; AC(2) = app.CB2.Value; AC(3) = app.CB3.Value; AC(4) = app.CB4.Value; AC(5) = app.CB5.Value;


           for px = 1:5
            
            if AC(px) == 1
                
                app.RunningLamp.Enable = 1;
                
                eval(['cluster_name = app.Cluster' num2str(px) '_name.Value']);
                eval(['cluster_indx = ' 'app.Cluster' num2str(px) '.Value']);
                cluster_indx = eval(cluster_indx);
                clusdata2 = app.myStruct.cluster_out.fitdata;
                indx_data = clusdata2(ismember(app.myStruct.cluster_out.T,cluster_indx),:);
                
                if str2double(app.median_mean_2.Value) == 1
                     dataclust_t = median(indx_data,1);
                else
                     dataclust_t = mean(indx_data,1);
                end
                
                
                dataclust_t = reshape(dataclust_t,size(app.myStruct.cluster_out.data,4),size(app.myStruct.cluster_out.data,5));
                dataclust(1,:,:) = dataclust_t;
                
                out.clusterindex(px).a = cluster_indx;
                
                startpoint = [app.myStruct.R1_guess_2(px,:) -max(dataclust(:)) max(dataclust(:)) (app.myStruct.Noise_guess_2(px)/100)*max(dataclust(:))];
                [fitobject,gof,R1out] = fit_relaxation_cluster_2(dataclust,t,Bevo,B0_pol,startpoint,app.myStruct.outliers);
                

    out.indx{px} = px;
    out.name{px} = cluster_name;
    
    out.tR1(px,:) = R1out;
    out.tgamma(px) = fitobject.gamma; 
    out.tgammad(px) = fitobject.gammad;
    out.tnoise(px) = fitobject.noise; 
    out.tres(px) = gof.rsquare; 
    out.c = confint(fitobject);
    out.tdR1(px,:) = diff(out.c(:,1:4))./2;    
    out.dataclust{px} = dataclust;                
    out.startpoint{px} = startpoint;     
    out.Bevo{px} = Bevo;
    out.B0 = Bevo;
    out.data(px).a = dataclust_t;
    out.time(px).a = t;
    out.B0_pol = B0_pol;
    if str2double(app.median_mean_2.Value) == 1
    out.average_method = 'median';
     else
    out.average_method = 'mean';
     end
    out.outliers = app.myStruct.outliers;
    
                clear dataclust
               
            end
            
           end
           

indxc(1) = app.CB1.Value; indxc(2) = app.CB2.Value; indxc(3) = app.CB3.Value; indxc(4) = app.CB4.Value; indxc(5) = app.CB5.Value;
B = [1 2 3 4 5];
B = B(indxc>0);


           for cx = 1:length(B) 
               
               eval(['temp = app.Cluster' num2str(B(cx)) '_name.Value'])
               
               A{(cx)} = temp;
               D{(cx)} = num2str(B(cx));
               C{(cx)} = out.indx(B(cx));
                
           end
           
           out.names = A;
           out.index_ROI = C;
           
           
           app.definefit.Items = A;
           app.definefit.Value = app.definefit.Items{1};
           app.definefit.ItemsData = D;
           app.allfit.Value = app.cluster_option.Items{1};
           app.definefit_CB.Value = 1; 
           app.allfit_CB.Value = 0;
           app.cluster_option_plot_2.Items = A;
           app.cluster_option_plot_2.ItemsData = D;
           
           defined_cluster_fit = out;
           app.myStruct.defined_cluster_fit = out;
           app.myStruct.defined_cluster_fit.cluster_out = app.myStruct.cluster_out;
        
%            save('defined_cluster_fit','defined_cluster_fit')
            app.RunningLamp.Enable = 0;
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            
           [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
            fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
                    
            maskout(maskout>0) = 1;
            imagesc(app.UIAxes_10, maskout(:,:,1,end,end));
            colormap(app.UIAxes_10,gray)
            app.myStruct.mask = maskout;
            
        end

        % Button pushed function: fitallclusters
        function fitallclustersPushed(app, event)
            
            clear app.myStruct.all_cluster_fit;
            
            app.RunningLamp_2.Enable = 1;
            [cluster_fit] = FFC_cluster_fit(app.myStruct.cluster_out ,app.minclustersize.Value, app.UITable_4.Data(:,2)',app.noise_3.Value,str2double(app.median_mean_3.Value),app.myStruct.outliers);
%             save('cluster_fit','cluster_fit')
            app.myStruct.all_cluster_fit = cluster_fit;
            app.RunningLamp_2.Enable = 0;
            
            cluster_new = cluster_fit.cluster(cluster_fit.cluster > 0);
            
            T = cluster_fit.T;
            
            for bx = 1:length(cluster_new)
                
                sizecluster(bx) = length(T(T==cluster_new(bx)));
                
            end
           
            [sizenew, I] = sort(sizecluster,'descend');
            
            [clusters] = cluster_new(I) ;
            
            app.myStruct.all_cluster_fit.clusters  = clusters;
            app.myStruct.all_cluster_fit.clustersize = sizenew;
            
          for cx = 1:length(clusters); A{cx} = num2str(clusters(cx)); end
           app.cluster_option.Items = A(2:end);
           
           app.allfit.Items = A(2:end);
           app.cluster_option.Value = app.cluster_option.Items{1};
           app.allfit.Value = app.cluster_option.Items{1};
           app.definefit_CB.Value = 0; 
           app.allfit_CB.Value = 1;
    
           
           app.multipleclusters_V.Value = ['[' A{2} ',' A{3} ']'];
            
           app.cluster_option_plot.Items = A(2:end);
           app.cluster_option_plot.Value = app.cluster_option.Items{1};
           
           app.myStruct.all_cluster_fit.cluster_out = app.myStruct.cluster_out;
          
           
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)

            namet = app.maskname_2.Value;
            
            namet = namet(1:end-4);
            
            eval([namet '= app.myStruct.mask;']);
            
            eval(['save(app.maskname_2.Value,' '''' namet ''' )'])
            
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)


            colours = {'k';'b';'r';'g';'y'};
            

            app.UIAxes_7.cla;
            
if app.multipleclusters_CB.Value == 1
 
cluster_indx = app.multipleclusters_V.Value;
cluster_indx = eval(cluster_indx);
cfit = app.myStruct.all_cluster_fit;
end 

if app.singleclusters_CB.Value == 1

cluster_indx = app.cluster_option.Value;
cluster_indx = str2double(cluster_indx);
cfit = app.myStruct.all_cluster_fit;
end 

if app.singleclusters_CB.Value == 1 || app.multipleclusters_CB.Value == 1

for bx = 1:length(cluster_indx)
cdx = cluster_indx(bx);
fields = app.myStruct.fieldpoints;
[fields, I] = sort(fields);
R1 = cfit.tR1(cdx,:);
R1 = R1(I);
hold(app.UIAxes_7)
plot(app.UIAxes_7, fields, R1,char(colours(bx)),'Linewidth',3); hold(app.UIAxes_7)
K{bx} = num2str(cdx);
end

if app.ylog_CB.Value == 1
app.UIAxes_7.YScale = 'log';
end   
if app.xlog_CB.Value == 1
app.UIAxes_7.XScale = 'log';
end

if app.ylog_CB.Value == 0
app.UIAxes_7.YScale = 'linear';
end   
if app.xlog_CB.Value == 0
app.UIAxes_7.XScale = 'linear';
end

 legend(app.UIAxes_7,K)
 app.UIAxes_7.XLabel.String = 'Field (mT)';
 app.UIAxes_7.YLabel.String = 'R_1 (s^{-1})';
 set(gca,'FontSize', 10,'fontweight','bold','Linewidth',1.5)
 if app.autoscale_CB.Value == 0
 app.UIAxes_7.YLim = [app.ymin.Value,app.ymax.Value];
 app.UIAxes_7.XLim = [app.xmin.Value, app.xmax.Value];
 end
 
  if app.autoscale_CB.Value == 1
  app.UIAxes_7.XLimMode = 'auto';
  app.UIAxes_7.YLimMode = 'auto'; 
  end 
  
  app.UIAxes_7.XTick = [round(fields)];

fig = figure

for bx = 1:length(cluster_indx)
cdx = cluster_indx(bx);
fields = app.myStruct.fieldpoints;
[fields, I] = sort(fields);
R1 = cfit.tR1(cdx,:);
R1 = R1(I);
plot(fields, R1,'-o','Linewidth',3,'MarkerSize',6,'Color',char(colours(bx)),'MarkerFaceColor',char(colours(bx))); hold on
K{bx} = num2str(cdx);
end

for bx = 1:length(cluster_indx)
cdx = cluster_indx(bx);
if app.errorbar_CB.Value == 1
errorbar(app.myStruct.fieldpoints,cfit.tR1(cdx,:),cfit.tdR1(cdx,:),cfit.tdR1(cdx,:),char(colours(bx)),'LineStyle','none','Linewidth',3, 'CapSize',20); hold on
end
scatter(app.myStruct.fieldpoints, cfit.tR1(cdx,:),50,char(colours(bx)),'Filled'); hold on
end

legend(K)

set(gca,'FontSize', 16,'fontweight','bold','Linewidth',1.5)

if app.autoscale_CB.Value == 0
    
    ylim([app.ymin.Value,app.ymax.Value])
    xlim([app.xmin.Value, app.xmax.Value])
    
end

if app.ylog_CB.Value == 1
    set(gca, 'YScale', 'log')
end
    
if app.xlog_CB.Value == 1
    set(gca, 'XScale', 'log')
end

ylabel('R_1 (s^{-1})')
xlabel('Field (mT)')           

if app.savefig_CB.Value == 1
saveas(fig,app.figname.Value)
end 

end 

if app.singleclusters_CB.Value == 0 && app.multipleclusters_CB.Value == 0

pp = app.myStruct.defined_cluster_fit;

B = [1 2 3 4 5];

indxc(1) = app.A1.Value; indxc(2) = app.A2.Value; indxc(3) = app.A3.Value; indxc(4) = app.A4.Value; indxc(5) = app.A5.Value;
clear R1  
B = B(indxc>0);

for cx = 1:length(indxc(indxc>0))
    
temp = indxc(indxc>0);
 
R1(cx,:) = pp.tR1(B(cx),:);
tdR1(cx,:) = pp.tdR1(B(cx),:);

end

fields = app.myStruct.fieldpoints';
[fields, I] = sort(fields);  

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold(app.UIAxes_7)
plot(app.UIAxes_7,fields, R1p,char(colours(B(cx))),'Linewidth',3); hold(app.UIAxes_7)
K{cx} = char(pp.name(B(cx)));

end

if app.ylog_CB.Value == 1
app.UIAxes_7.YScale = 'log';
end   
if app.xlog_CB.Value == 1
app.UIAxes_7.XScale = 'log';
end

if app.ylog_CB.Value == 0
app.UIAxes_7.YScale = 'linear';
end   
if app.xlog_CB.Value == 0
app.UIAxes_7.XScale = 'linear';
end

 legend(app.UIAxes_7,K)
 app.UIAxes_7.XLabel.String = 'Field (mT)';
 app.UIAxes_7.YLabel.String = 'R_1 (s^{-1})';
 set(gca,'FontSize', 12,'fontweight','bold','Linewidth',1.5)
 if app.autoscale_CB.Value == 0
 app.UIAxes_7.YLim = [app.ymin.Value,app.ymax.Value];
 app.UIAxes_7.XLim = [app.xmin.Value, app.xmax.Value];
 end
 
  if app.autoscale_CB.Value == 1
  app.UIAxes_7.XLimMode = 'auto';
  app.UIAxes_7.YLimMode = 'auto'; 
  end 
  
  app.UIAxes_7.XTick = [round(fields)];
  
fig = figure;

pp = app.myStruct.defined_cluster_fit;

B = [1 2 3 4 5];

indxc(1) = app.A1.Value; indxc(2) = app.A2.Value; indxc(3) = app.A3.Value; indxc(4) = app.A4.Value; indxc(5) = app.A5.Value;
clear R1  
B = B(indxc>0);

for cx = 1:length(indxc(indxc>0))
    
temp = indxc(indxc>0);
 
R1(cx,:) = pp.tR1(B(cx),:);
tdR1(cx,:) = pp.tdR1(B(cx),:);

end

fieldst = app.myStruct.fieldpoints';
[fields, I] = sort(fieldst);  

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold on
plot(fields, R1p,'-o','Linewidth',3,'MarkerSize',6,'Color',char(colours(B(cx))),'MarkerFaceColor',char(colours(B(cx)))); hold on
% errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(cx)),'LineStyle','none','Linewidth',3); hold on
K{cx} = char(pp.name(B(cx)));
end

if app.errorbar_CB.Value == 1

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold on
errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(B(cx))),'LineStyle','none','Linewidth',3, 'CapSize',20); hold on
end

end

legend(K)

set(gca,'FontSize', 16,'fontweight','bold','Linewidth',1.5)

if app.autoscale_CB.Value == 0
    
    ylim([app.ymin.Value,app.ymax.Value])
    xlim([app.xmin.Value, app.xmax.Value])
    
end

if app.ylog_CB.Value == 1
    set(gca, 'YScale', 'log')
end
    
if app.xlog_CB.Value == 1
    set(gca, 'XScale', 'log')
end

ylabel('R_1 (s^{-1})')
xlabel('Field (mT)')           

if app.savefig_CB.Value == 1
saveas(fig,app.figname.Value)
end 

end
    
close Figure 1
        
        end

        % Value changed function: autoscale_CB
        function autoscale_CBValueChanged(app, event)
            value = app.autoscale_CB.Value;
            
            if value == 1
                
                app.xmin.Enable = 0;
                app.xmax.Enable = 0;
                app.ymin.Enable = 0;
                app.ymax.Enable = 0;
                
                
            end
            

            if value == 0
                
                app.xmin.Enable = 1;
                app.xmax.Enable = 1;
                app.ymin.Enable = 1;
                app.ymax.Enable = 1;
                
                
            end
            
        end

        % Value changed function: multipleclusters_CB
        function multipleclusters_CBValueChanged(app, event)
            value = app.multipleclusters_CB.Value;
            
            if value == 1
                app.singleclusters_CB.Value = 0;
  app.A1.Value = 0; app.A2.Value = 0; app.A3.Value = 0; app.A4.Value = 0; app.A5.Value = 0;
            end
            
            if value == 0
                app.singleclusters_CB.Value = 1;
            end
            
        end

        % Value changed function: singleclusters_CB
        function singleclusters_CBValueChanged(app, event)
            value = app.singleclusters_CB.Value;
            
            
                if value == 1
                app.multipleclusters_CB.Value = 0;
                app.A1.Value = 0; app.A2.Value = 0; app.A3.Value = 0; app.A4.Value = 0; app.A5.Value = 0;
                end
            
            if value == 0
                app.multipleclusters_CB.Value = 1;
            end


        end

        % Value changed function: compCB
        function compCBValueChanged(app, event)
            value = app.compCB.Value;
            
            if value == 1
                app.magCB.Value = 0;
            end
            
            if value == 0
                app.magCB.Value = 1;
            end


        end

        % Value changed function: magCB
        function magCBValueChanged(app, event)
            value = app.magCB.Value;
            
            if value == 1
                app.compCB.Value = 0;
            end
            
            if value == 0
                app.compCB.Value = 1;
            end
            
        end

        % Value changed function: savefig_CB
        function savefig_CBValueChanged(app, event)
            value = app.savefig_CB.Value;
            
            if value == 1
                app.figname.Enable = 1;
            end
            
            if value == 0
                app.figname.Enable = 0;
            end
            
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            tempval = double(app.compCB.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            AT = app.myStruct.cluster_out.AT;
            
            imt = app.myStruct.cluster_out.clusterimg;
           
            imgt = zeros(app.myStruct.dim(1),app.myStruct.dim(2));
            [rowt,colt] = find(imt == str2double(app.cluster_option_plot.Value));
            
            for vx = 1:length(rowt)
            imgt(rowt(vx),colt(vx)) = 1;
            end
           
            
            imagesc(app.UIAxes_cluster_3,imgt)
            dim = size(app.myStruct.magimage);
            app.myStruct.dim = dim;
            app.UIAxes_cluster_3.XLim = [0 dim(2)];
            app.UIAxes_cluster_3.YLim = [0 dim(1)];
            app.UIAxes_cluster_3.XTick = [];
            app.UIAxes_cluster_3.YTick = [];
            colormap(app.UIAxes_cluster_3,gray)
            
%             fig = figure; 
%             imagesc(imgt);
%             colormap gray
%             axis image
%             xticks([])
%             yticks([])
%             title(app.cluster_option_plot.Value)
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            imgt(imgt == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,imgt); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(imgt)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            if app.savefig_CB_2.Value  == 1
            savefig(app.figname_2.Value)
            end 
            
            
        end

        % Value changed function: A1
        function A1ValueChanged(app, event)
            value = app.A1.Value;
            
                if value == 1
                app.multipleclusters_CB.Value = 0;
                app.singleclusters_CB.Value = 0 ;
                end
            
            
        end

        % Callback function
        function ExportfigureButton_9Pushed(app, event)


        end

        % Value changed function: allfit_CB
        function allfit_CBValueChanged(app, event)
            value = app.allfit_CB.Value;
           
           if value == 1
           app.definefit_CB.Value = 0;
           end
           if value == 0
           app.definefit_CB.Value = 1;
           end
           
           
        end

        % Value changed function: definefit_CB
        function definefit_CBValueChanged(app, event)
            value = app.definefit_CB.Value;
            
           if value == 1
           app.allfit_CB.Value = 0;
           end
           if value == 0
           app.allfit_CB.Value = 1;
           end
            
        end

        % Value changed function: autoscale_CB_2
        function autoscale_CB_2ValueChanged(app, event)
            value = app.autoscale_CB_2.Value;


            if value == 1
                
                app.xmin_2.Enable = 0;
                app.xmax_2.Enable = 0;
                app.ymin_2.Enable = 0;
                app.ymax_2.Enable = 0;
                
                
            end
            

            if value == 0
                
                app.xmin_2.Enable = 1;
                app.xmax_2.Enable = 1;
                app.ymin_2.Enable = 1;
                app.ymax_2.Enable = 1;
                
                
            end
            
        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)


            if app.allfit_CB.Value == 1
            
               struct = app.myStruct.all_cluster_fit;
               indx = str2double(app.allfit.Value); 
               B0 = struct.Bevo;
               R1 = struct.tR1(indx,:);
               gamma_in = struct.tgamma(indx);
               gammad_in = struct.tgammad(indx);
               noise_in = struct.tnoise(indx);
               B0pol = struct.B0pol;
               data = struct.data(indx).a;
               t = struct.time(indx).a;
               name = num2str(indx);
               app.r2.Value = struct.tres(indx);
               
            end
            
            if app.definefit_CB.Value == 1
                
                struct = app.myStruct.defined_cluster_fit;
                indx = str2double(app.definefit.Value);
                B0 = struct.B0;
                R1 = struct.tR1(indx,:);
                gamma_in = struct.tgamma(indx);
                gammad_in = struct.tgammad(indx);
                noise_in = struct.tnoise(indx);
                B0pol = struct.B0_pol;
                data = struct.data(indx).a;
                t = struct.time(indx).a;
                name = struct.name{1,indx};
                app.r2.Value = struct.tres(indx);
            end
       

   fig = figure;

if length(B0) > 3
tiledlayout(ceil(length(B0)/2),ceil(length(B0)/2))
else 
tiledlayout(1,ceil(length(B0)))
end 


 B0 = B0./1000;
 
for bx = 1:length(B0)
    
nexttile
          
R1_in = R1(bx);
B0_in = B0(bx);
  
dimt = size(t);

yp(:) = data(:,bx);

wp = logical(app.myStruct.outliers);

wp2 = reshape(wp,[dimt(1),dimt(2)]);

t(wp2 == 0) = NaN; 

tt =  t(bx,:);   

t_in = 0:0.002:max(t(:));

if app.autoscale_CB_2.Value == 0

    t_in = 0:0.002:app.xmax_2.Value;
    
end

    f = model_fit(R1_in,gamma_in,gammad_in,B0_in,noise_in,t_in,B0pol);


    plot(t_in,f,'r','LineWidth',4); hold on
    scatter(tt,yp,45,'k','markerfacecolor','k')
    
    set(gca,'FontSize', 10,'fontweight','bold','Linewidth',1.5)
    xlabel({'T_e_v (s)'},'FontSize', 14)
    ylabel({'Signal'}, 'FontSize', 14)
    title([num2str(B0_in*1000) ' (mT)'],'FontSize', 14)
    
    if app.autoscale_CB_2.Value == 0
    
    ylim([app.ymin_2.Value,app.ymax_2.Value])
    xlim([app.xmin_2.Value, app.xmax_2.Value])
    
end
     
     if bx == length(B0)
        legend(name)
     end
      
end

if app.savefig_CB_4.Value == 1
    
    saveas(fig,app.figname_4.Value)
    
end


if length(B0) > 3
pp = tiledlayout(app.Panel_8,ceil(length(B0)/2),ceil(length(B0)/2));
else 
pp = tiledlayout(app.Panel_8,ceil(length(B0)/2),ceil(length(B0)/2));
end 

% Tile 1


for bx = 1:length(B0)
    
eval(['ax' num2str(bx) '= nexttile(pp)']);

eval(['jx = ax' num2str(bx)])
          
R1_in = R1(bx);
B0_in = B0(bx);

dimt = size(t);

yp(:) = data(:,bx);

wp = logical(app.myStruct.outliers);

wp2 = reshape(wp,[dimt(1),dimt(2)]);

t(wp2 == 0) = NaN; 

tt =  t(bx,:); 

t_in = 0:0.002:max(t(:));

if app.autoscale_CB_2.Value == 0

    t_in = 0:0.002:app.xmax_2.Value;
    
end

    f = model_fit(R1_in,gamma_in,gammad_in,B0_in,noise_in,t_in,B0pol);


    plot(jx,t_in,f,'r','LineWidth',2); hold(jx)
    scatter(jx,tt,yp,20,'k','markerfacecolor','k')
    
%  jx.XLabel.String = 'Time (s)';
%  jx.YLabel.String = 'Signal';
 jx.Title.String = [num2str(B0_in*1000) ' (mT)'];
 set(jx,'FontSize', 8,'fontweight','bold','Linewidth',1.5)
 if app.autoscale_CB_2.Value == 0
 jx.YLim = [app.ymin_2.Value,app.ymax_2.Value];
 jx.XLim = [app.xmin_2.Value, app.xmax_2.Value];
 end
 
  if app.autoscale_CB_2.Value == 1
  jx.XLimMode = 'auto';
  jx.YLimMode = 'auto'; 
  end 
    
 
end


        end

        % Value changed function: definefit
        function definefitValueChanged(app, event)
            value = app.definefit.Value;


        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
            tempval = double(app.compCB.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            imt = app.myStruct.cluster_out.clusterimg;
           
            imgt = zeros(app.myStruct.dim(1),app.myStruct.dim(2));
            
            clusterindex = app.myStruct.defined_cluster_fit.clusterindex(str2double(app.cluster_option_plot_2.Value)).a;
            
            for nx = 1:length(clusterindex)
            
            [rowt,colt] = find(imt == clusterindex(nx));
            
            for vx = 1:length(rowt)
             imgt(rowt(vx),colt(vx)) = 1;
             end
            
            end
            
            ao = str2double(app.cluster_option_plot_2.Value);
            
            eval(['namet = app.Cluster' num2str(ao) '_name.Value'])

%            
            imagesc(app.UIAxes_cluster_3,imgt)
            dim = size(app.myStruct.magimage);
            app.myStruct.dim = dim;
            app.UIAxes_cluster_3.XLim = [0 dim(2)];
            app.UIAxes_cluster_3.YLim = [0 dim(1)];
            app.UIAxes_cluster_3.XTick = [];
            app.UIAxes_cluster_3.YTick = [];
            colormap(app.UIAxes_cluster_3,gray)
            
%             fig = figure; 
%             imagesc(imgt);
%             colormap gray
%             axis image
%             xticks([])
%             yticks([])
%             title(namet)

            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            imgt(imgt == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,imgt); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(imgt)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            if app.savefig_CB_3.Value  == 1
            savefig(app.figname_3.Value)
            end 
     

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            value = str2double(value);
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            if value == 1
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,kt,kf)));
            temp = abs(app.myStruct.compleximage(:,:,1,kt,kf));
            end
            
            if value == 0
            imagesc(app.UIAxes, app.myStruct.magimage(:,:,1,kt,kf));
            temp = app.myStruct.magimage(:,:,1,kt,kf);
            end    
            
            
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;  
           
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];


        end

        % Value changed function: A2
        function A2ValueChanged(app, event)
            value = app.A2.Value;
            
                      if value == 1
                app.multipleclusters_CB.Value = 0;
                app.singleclusters_CB.Value = 0 ;
                end
        end

        % Value changed function: A3
        function A3ValueChanged(app, event)
            value = app.A3.Value;
            
                      if value == 1
                app.multipleclusters_CB.Value = 0;
                app.singleclusters_CB.Value = 0 ;
                      end
                      
        end

        % Value changed function: A4
        function A4ValueChanged(app, event)
            value = app.A4.Value;
            
                      if value == 1
                app.multipleclusters_CB.Value = 0;
                app.singleclusters_CB.Value = 0 ;
                      end
                      
        end

        % Value changed function: A5
        function A5ValueChanged(app, event)
            value = app.A5.Value;
            
                         if value == 1
                app.multipleclusters_CB.Value = 0;
                app.singleclusters_CB.Value = 0 ;
                      end
            
        end

        % Button pushed function: fitallclusters_2
        function fitallclusters_2ButtonPushed(app, event)
            
            mkdir(app.foldername.Value)
            
            if app.all_CB.Value == 1
                
            CiD = app.myStruct.all_cluster_fit.clusters;   
            
           
            R1_in = app.myStruct.all_cluster_fit.tR1(CiD,:);
            R1d_in = app.myStruct.all_cluster_fit.tdR1(CiD,:);
            
            for px = 1:length(app.myStruct.fieldpoints)
%             
            eval(['R1_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1_in(:,px)']);
            eval(['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1d_in(:,px)']);
            str1{1,px} = ['R1_' num2str(round(app.myStruct.fieldpoints(px))) ','];    
            str2{1,px} = ['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ','];
            
            end
          
            Rsquare = app.myStruct.all_cluster_fit.tres(CiD)';


            newStr1 = join(str1);
            newStr2 = join(str2);
            
            temp = ['CiD,' newStr1 newStr2 ' Rsquare'];
            temp2 = join(temp);
            temp2 = char(temp2);
            
            
           eval([ 'T = table( ' temp2 ')'])
            
           filename = [app.foldername.Value '/' app.excelname.Value];
           writetable(T,filename,'Sheet',1)
           
           all_cluster_fit = app.myStruct.all_cluster_fit;
           all_cluster_fit.mask = app.myStruct.mask;
           save([app.foldername.Value '/' 'all_cluster_fit'],'all_cluster_fit')
       
        end
           
           if app.def_CB.Value == 1
                
            CiD = app.myStruct.defined_cluster_fit.names';   
            
           
            R1_in = app.myStruct.defined_cluster_fit.tR1;
            R1d_in = app.myStruct.defined_cluster_fit.tdR1;
            
            R1_in(R1_in(:,1)==0,:) = [];
            R1d_in(R1d_in(:,1)==0,:) = [];
            
            for px = 1:length(app.myStruct.fieldpoints)
%             
            eval(['R1_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1_in(:,px);']);
            eval(['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1d_in(:,px);']);
            str1{1,px} = ['R1_' num2str(round(app.myStruct.fieldpoints(px))) ','];    
            str2{1,px} = ['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ','];
            
            end
          
            Rsquare = app.myStruct.defined_cluster_fit.tres';
            
            Rsquare(Rsquare(:,1)==0,:) = [];
            
            newStr1 = join(str1);
            newStr2 = join(str2);
            
            temp = ['CiD,' newStr1 newStr2 ' Rsquare'];
            temp2 = join(temp);
            temp2 = char(temp2);
            
            
           eval([ 'T = table( ' temp2 ');']);
            
           if app.all_CB.Value == 1
           filename = [app.foldername.Value '/' app.excelname.Value];
           writetable(T,filename,'Sheet',2)
           end
           
           if app.all_CB.Value == 0
           filename = [app.foldername.Value '/' app.excelname.Value];
           writetable(T,filename,'Sheet',1)
           end
           
           defined_cluster_fit = app.myStruct.defined_cluster_fit;
           defined_cluster_fit.mask = app.myStruct.mask;
           save([app.foldername.Value '/' 'defined_cluster_fit'],'defined_cluster_fit')
           
           indx_clust = app.myStruct.defined_cluster_fit.index_ROI;
           clus_img = app.myStruct.defined_cluster_fit.cluster_out.clusterimg;
           
           for nx = 1:length(indx_clust)
               
               namet = app.myStruct.defined_cluster_fit.names{1,nx};
               
               dimt = size(app.myStruct.defined_cluster_fit.cluster_out.clusterimg);
               
               ROI = zeros(dimt(1),dimt(2));
               
            indtt = (app.myStruct.defined_cluster_fit.index_ROI{1, nx});
            tn = indtt{1,1};
            inc = app.myStruct.defined_cluster_fit.clusterindex(tn).a;
               
               ROI(clus_img == inc) = 1;
               
               save([app.foldername.Value '/' namet '_ROI.mat'],'ROI');


           end
           
           
            end
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            a = load([PathName,'/' Filename]);
            
            fields = fieldnames(a);
            fields = char(fields);
            
%             Filename = Filename(1:end-4);
            eval(['a.inputfile = a.' fields ';']);
                      
            obj.compleximage = a.inputfile.compleximage;
            obj.magimage = a.inputfile.magimage;
            obj.n_timepoints = a.inputfile.n_timepoints;
            obj.n_fieldpoints = a.inputfile.n_fieldpoints;
            obj.timepoints = a.inputfile.timepoints;
            obj.fieldpoints = a.inputfile.fieldpoints; 
            obj.B0_pol = a.inputfile.param{62, 3};


            app.myStruct = obj;
            app.myStruct.inputfile = a.inputfile;
            
            for ix=1:length(app.myStruct.fieldpoints)
                fielditems{ix} = num2str(app.myStruct.fieldpoints(ix));
            end
                   
            app.FieldDropDown.Items = fielditems;
            
            for ix=1:length(app.myStruct.timepoints(1,:))
                timeitems{ix} = num2str(app.myStruct.timepoints(1,ix));
            end
            
            app.TimeDropDown.Items = timeitems;
            
            imagesc(app.UIAxes, abs(app.myStruct.compleximage(:,:,1,1,1)));
            dim = size(app.myStruct.magimage);
            app.myStruct.dim = dim;
            app.UIAxes.XLim = [0 dim(2)];
            app.UIAxes.YLim = [0 dim(1)];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes,gray)
                       
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.XLim = [0 dim(2)];
            app.UIAxes_2.YLim = [0 dim(1)];
           
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.XLim = [0 dim(2)];
            app.UIAxes_10.YLim = [0 dim(1)];
            
            temp = abs(app.myStruct.compleximage(:,:,1,1,1));
            Slidermax = 2*(max(temp(:)));
            
            app.minSlider.Value = 0;
            app.minSlider.Limits = [0,Slidermax];
            app.minvalue.Value = app.minSlider.Value;
            
            app.maxSlider.Limits = [0,Slidermax];
            app.maxSlider.Value = Slidermax./2;
            app.maxvalue.Value = app.maxSlider.Value;        
           
            minvaluet = app.minSlider.Value;
            maxvaluet = app.maxSlider.Value;
            app.UIAxes.CLim = [minvaluet maxvaluet];
            
            app.threshold.Value = [1.15];
            app.ThresholdSlider.Limits = [app.threshold.Value-0.05,app.threshold.Value+0.05];
            app.ThresholdSlider.Value = [1.15];
       
            nofields = length(app.myStruct.fieldpoints);
            app.UITable_4.Data(:,1) = app.myStruct.fieldpoints;
            app.UITable_4.Data(:,2) = 2*ones(nofields,1);
            app.UITable_2.Data(:,1) = app.myStruct.fieldpoints;
            app.UITable_2.Data(:,2) = 2*ones(nofields,1);
            
            app.RunningLamp.Enable = 0;
            
            t = obj.timepoints; B0 = obj.fieldpoints;
            [~, number_times] = size(t);
             x = t; x = x(:);
             y = repmat(B0,1,number_times); y = y(:);
             
             app.UITable_3.Data(:,1) = y;
             app.UITable_3.Data(:,2) = x;
             app.UITable_3.Data(:,3) = ones(length(x),1);
             
             app.myStruct.outliers = app.UITable_3.Data(:,3);


             app.myStruct.R1_guess = NaN([5,nofields]);
             app.myStruct.Noise_guess = NaN(1,5);
             app.myStruct.R1_guess_2 = NaN([5,nofields]);
             app.myStruct.Noise_guess_2 = NaN(1,5);
             
        end

        % Button pushed function: DrawButton_1
        function DrawButton_1Pushed(app, event)
            
               tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            img_in = img_in.*app.myStruct.mask; 
            
            valueswitch = str2double(app.Switch_3.Value);
            
            if valueswitch == 1
                
                circle_size = app.circlesize.Value;
                
            maskout = draw_circle_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask,circle_size);
                
            else 
        
            maskout = draw_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask);
            
            end
            
            app.myStruct.ROI_1.mask = maskout; 
            app.myStruct.ROI_1.name = app.ROI1_name.Value; 
           
         
        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
                
               tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            img_in = img_in.*app.myStruct.mask; 
            
            valueswitch = str2double(app.Switch_3.Value);
            
            if valueswitch == 1
                
                circle_size = app.circlesize.Value;
                
            maskout = draw_circle_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask,circle_size);
                
            else 
        
            maskout = draw_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask);
            
            end
            
            
            app.myStruct.ROI_2.mask = maskout; 
            app.myStruct.ROI_2.name = app.ROI2_name.Value; 
        end

        % Button pushed function: DrawButton_3
        function DrawButton_3Pushed(app, event)
                  tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            img_in = img_in.*app.myStruct.mask; 
            
            valueswitch = str2double(app.Switch_3.Value);
            
            if valueswitch == 1
                
                circle_size = app.circlesize.Value;
                
            maskout = draw_circle_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask,circle_size);
                
            else 
        
            maskout = draw_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask);
            
            end
            
            app.myStruct.ROI_3.mask = maskout; 
            app.myStruct.ROI_3.name = app.ROI3_name.Value; 
       
        end

        % Button pushed function: DrawButton_4
        function DrawButton_4Pushed(app, event)
                   tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            img_in = img_in.*app.myStruct.mask; 
            
            valueswitch = str2double(app.Switch_3.Value);
            
            if valueswitch == 1
                
                circle_size = app.circlesize.Value;
                
            maskout = draw_circle_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask,circle_size);
                
            else 
        
            maskout = draw_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask);
            
            end
            
            
            app.myStruct.ROI_4.mask = maskout; 
            app.myStruct.ROI_4.name = app.ROI4_name.Value; 
           
        end

        % Button pushed function: DrawButton_5
        function DrawButton_5Pushed(app, event)
              tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            img_in = img_in.*app.myStruct.mask; 
            
            valueswitch = str2double(app.Switch_3.Value);
            
            if valueswitch == 1
                
                circle_size = app.circlesize.Value;
                
            maskout = draw_circle_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask,circle_size);
                
            else 
        
            maskout = draw_ROI(img_in,[app.minSlider.Value,app.maxSlider.Value],app.myStruct.mask);
            
            end
            
            
            app.myStruct.ROI_5.mask = maskout; 
            app.myStruct.ROI_5.name = app.ROI5_name.Value; 
            
        end

        % Button pushed function: RunfitButton_2
        function RunfitButton_2Pushed(app, event)
            
           clear app.myStruct.ROI;
            
           B0_pol = app.myStruct.B0_pol./1000;
           t = app.myStruct.timepoints./1000;
           Bevo = app.myStruct.fieldpoints;
           
           AC(1) = app.CB1_2.Value; AC(2) = app.CB2_2.Value; AC(3) = app.CB3_2.Value; AC(4) = app.CB4_2.Value; AC(5) = app.CB5_2.Value;
           
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            dim = size(img_int);
            img_int = squeeze(img_int);
             
             
           app.RunningLamp_3.Enable = 1;
           
           for px = 1:5
            
            if AC(px) == 1
                
             eval(['ROI = app.myStruct.ROI_' num2str(px) '.mask;'])
             eval(['ROIname = app.ROI' num2str(px) '_name.Value;'])
            
             
             for lx = 1:dim(4)
                 for mx = 1:dim(5)
                     
                     imgt(:,:) = img_int(:,:,lx,mx);
                     
                     if str2double(app.median_mean.Value) == 1
                     datain(1,lx,mx) = median(imgt(logical(ROI)));
                     else
                     datain(1,lx,mx) = mean(imgt(logical(ROI)));
                     end
                     
                 end  
             end
               
                
                startpoint = [app.myStruct.R1_guess(px,:) -max(datain(:)) max(datain(:)) (app.myStruct.Noise_guess(px)/100)*max(datain(:))];
                [fitobject,gof,R1out] = fit_relaxation_cluster_2(datain,t,Bevo,B0_pol,startpoint,app.myStruct.outliers);
                

    out.indx{px} = px;
    out.name{px} = ROIname;
    
    out.tR1(px,:) = R1out;
    out.tgamma(px) = fitobject.gamma; 
    out.tgammad(px) = fitobject.gammad;
    out.tnoise(px) = fitobject.noise; 
    out.tres(px) = gof.rsquare; 
    out.c = confint(fitobject);
    out.tdR1(px,:) = diff(out.c(:,1:4))./2;    
    out.datain{px} = datain;                
    out.startpoint{px} = startpoint;     
    out.Bevo{px} = Bevo;
    out.B0 = Bevo;
    out.time(px).a = t;
    out.B0_pol = B0_pol;
     if str2double(app.median_mean.Value) == 1
    out.average_method = 'median';
     else
    out.average_method = 'mean';
     end
    
    app.myStruct.ROI.fit = out;
    
                clear datain
               
            end
            
           end
          
           
indxc(1) = app.CB1_2.Value; indxc(2) = app.CB2_2.Value; indxc(3) = app.CB3_2.Value; indxc(4) = app.CB4_2.Value; indxc(5) = app.CB5_2.Value;
B = [1 2 3 4 5];
B = B(indxc>0);


           for cx = 1:length(B) 
               
%                temp = app.myStruct.ROI.fit.name{1,B(cx)};

 B(cx)

        eval(['temp = app.ROI' num2str(B(cx)) '_name.Value'])
               
               A{(cx)} = temp;
               D{(cx)} = num2str(B(cx));
                
           end
           
     out.A = A;      
     app.myStruct.ROI.fit = out;
           
           app.ROIList2.Items = A;
           app.figname_ROI.Value = [A{(1)} '.fig'];
           app.ROIList2.ItemsData = D; 
           app.ROIlist.Items = A';
           app.ROIlist.ItemsData = D;
           
            app.RunningLamp_3.Enable = 0;
            
        end

        % Callback function
        function UpdateButton_7Pushed(app, event)
               
colours = {'k';'b';'r';'g';'y'};
            
app.UIAxes_11.cla;
         

display('x')

pp = app.myStruct.defined_cluster_fit;

% B = [1 2 3 4 5];
% 
% indxc(1) = app.A1.Value; indxc(2) = app.A2.Value; indxc(3) = app.A3.Value; indxc(4) = app.A4.Value; indxc(5) = app.A5.Value;
% clear R1  
% B = B(indxc>0);
% 
% for cx = 1:length(indxc(indxc>0))
%     
% temp = indxc(indxc>0);
%  
% R1(cx,:) = pp.tR1(B(cx),:);
% tdR1(cx,:) = pp.tdR1(B(cx),:);
% 
% end
% 
% fields = app.myStruct.fieldpoints';
% [fields, I] = sort(fields);  
% 
% for cx = 1:length(temp)
% 
% R1p = R1(cx,:);
% R1p = R1p(I);
% 
% hold(app.UIAxes_7)
% plot(app.UIAxes_7,fields, R1p,char(colours(B(cx))),'Linewidth',3); hold(app.UIAxes_7)
% K{cx} = char(pp.name(B(cx)));
% 
% end
% 
% if app.ylog_CB.Value == 1
% app.UIAxes_7.YScale = 'log';
% end   
% if app.xlog_CB.Value == 1
% app.UIAxes_7.XScale = 'log';
% end
% 
% if app.ylog_CB.Value == 0
% app.UIAxes_7.YScale = 'linear';
% end   
% if app.xlog_CB.Value == 0
% app.UIAxes_7.XScale = 'linear';
% end
% 
%  legend(app.UIAxes_7,K)
%  app.UIAxes_7.XLabel.String = 'Field (mT)';
%  app.UIAxes_7.YLabel.String = 'R_1 (s^{-1})';
%  set(gca,'FontSize', 12,'fontweight','bold','Linewidth',1.5)
%  if app.autoscale_CB.Value == 0
%  app.UIAxes_7.YLim = [app.ymin.Value,app.ymax.Value];
%  app.UIAxes_7.XLim = [app.xmin.Value, app.xmax.Value];
%  end
%  
%   if app.autoscale_CB.Value == 1
%   app.UIAxes_7.XLimMode = 'auto';
%   app.UIAxes_7.YLimMode = 'auto'; 
%   end 
%   
%   app.UIAxes_7.XTick = [round(fields)];
%   
% fig = figure;
% 
% pp = app.myStruct.defined_cluster_fit;
% 
% B = [1 2 3 4 5];
% 
% indxc(1) = app.A1.Value; indxc(2) = app.A2.Value; indxc(3) = app.A3.Value; indxc(4) = app.A4.Value; indxc(5) = app.A5.Value;
% clear R1  
% B = B(indxc>0);
% 
% for cx = 1:length(indxc(indxc>0))
%     
% temp = indxc(indxc>0);
%  
% R1(cx,:) = pp.tR1(B(cx),:);
% tdR1(cx,:) = pp.tdR1(B(cx),:);
% 
% end
% 
% fieldst = app.myStruct.fieldpoints';
% [fields, I] = sort(fieldst);  
% 
% for cx = 1:length(temp)
% 
% R1p = R1(cx,:);
% R1p = R1p(I);
% 
% hold on
% plot(fields, R1p,char(colours(B(cx))),'Linewidth',3); hold on
% % errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(cx)),'LineStyle','none','Linewidth',3); hold on
% K{cx} = char(pp.name(B(cx)));
% end
% 
% if app.errorbar_CB.Value == 1
% 
% for cx = 1:length(temp)
% 
% R1p = R1(cx,:);
% R1p = R1p(I);
% 
% hold on
% errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(B(cx))),'LineStyle','none','Linewidth',3); hold on
% end
% 
% end
% 
% legend(K)
% 
% set(gca,'FontSize', 16,'fontweight','bold','Linewidth',1.5)
% 
% if app.autoscale_CB.Value == 0
%     
%     ylim([app.ymin.Value,app.ymax.Value])
%     xlim([app.xmin.Value, app.xmax.Value])
%     
% end
% 
% if app.ylog_CB.Value == 1
%     set(gca, 'YScale', 'log')
% end
%     
% if app.xlog_CB.Value == 1
%     set(gca, 'XScale', 'log')
% end
% 
% ylabel('R_1 (s^{-1})')
% xlabel('Field (mT)')           
% 
% if app.savefig_CB.Value == 1
% saveas(fig,app.figname.Value)
% end 


        end

        % Button pushed function: UpdateButton_9
        function UpdateButton_9Pushed(app, event)


colours = {'k';'b';'r';'g';'y'};
            
app.UIAxes_11.cla;

pp = app.myStruct.ROI.fit;
            
 B = [1 2 3 4 5];

indxc(1) = app.A1_2.Value; indxc(2) = app.A2_2.Value; indxc(3) = app.A3_2.Value; indxc(4) = app.A4_2.Value; indxc(5) = app.A5_2.Value;
clear R1  
B = B(indxc>0);

for cx = 1:length(indxc(indxc>0))
    
temp = indxc(indxc>0);
 
R1(cx,:) = pp.tR1(B(cx),:);
tdR1(cx,:) = pp.tdR1(B(cx),:);

end

fields = app.myStruct.fieldpoints';
[fields, I] = sort(fields);  

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold(app.UIAxes_11);
plot(app.UIAxes_11,fields, R1p,char(colours(B(cx))),'Linewidth',3); hold(app.UIAxes_11)
K{cx} = char(pp.name(B(cx)));

end

if app.ylog_CB_2.Value == 1
app.UIAxes_11.YScale = 'log';
end   
if app.xlog_CB_2.Value == 1
app.UIAxes_11.XScale = 'log';
end

if app.ylog_CB_2.Value == 0
app.UIAxes_11.YScale = 'linear';
end   
if app.xlog_CB_2.Value == 0
app.UIAxes_11.XScale = 'linear';
end

 legend(app.UIAxes_11,K);
 app.UIAxes_11.XLabel.String = 'Field (mT)';
 app.UIAxes_11.YLabel.String = 'R_1 (s^{-1})';
 set(gca,'FontSize', 12,'fontweight','bold','Linewidth',1.5)
 if app.autoscale_CB_3.Value == 0
 app.UIAxes_11.YLim = [app.ymin_3.Value,app.ymax_3.Value];
 app.UIAxes_11.XLim = [app.xmin_3.Value, app.xmax_3.Value];
 end
 
  if app.autoscale_CB_3.Value == 1
  app.UIAxes_11.XLimMode = 'auto';
  app.UIAxes_11.YLimMode = 'auto'; 
  end 
  
  app.UIAxes_11.XTick = [round(fields)];
  
  figure;
  
fig = figure;

% pp = app.myStruct.defined_cluster_fit;

B = [1 2 3 4 5];

indxc(1) = app.A1_2.Value; indxc(2) = app.A2_2.Value; indxc(3) = app.A3_2.Value; indxc(4) = app.A4_2.Value; indxc(5) = app.A5_2.Value;
clear R1  
B = B(indxc>0);

for cx = 1:length(indxc(indxc>0))
    
temp = indxc(indxc>0);
 
R1(cx,:) = pp.tR1(B(cx),:);
tdR1(cx,:) = pp.tdR1(B(cx),:);

end

fieldst = app.myStruct.fieldpoints';
[fields, I] = sort(fieldst);  

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold on
plot(fields, R1p,'-o','Linewidth',3,'MarkerSize',6,'Color',char(colours(B(cx))),'MarkerFaceColor',char(colours(B(cx)))); hold on
% errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(cx)),'LineStyle','none','Linewidth',3); hold on
K{cx} = char(pp.name(B(cx)));
end

if app.errorbar_CB_2.Value == 1

for cx = 1:length(temp)

R1p = R1(cx,:);
R1p = R1p(I);

hold on
errorbar(fieldst,R1(cx,:),tdR1(cx,:),tdR1(cx,:),char(colours(B(cx))),'LineStyle','none','Linewidth',3, 'CapSize',20); hold on
end

end

legend(K)

set(gca,'FontSize', 16,'fontweight','bold','Linewidth',1.5)

if app.autoscale_CB_3.Value == 0
    
    ylim([app.ymin_3.Value,app.ymax_3.Value])
    xlim([app.xmin_3.Value, app.xmax_3.Value])
    
end

if app.ylog_CB_2.Value == 1
    set(gca, 'YScale', 'log')
end
    
if app.xlog_CB_2.Value == 1
    set(gca, 'XScale', 'log')
end

ylabel('R_1 (s^{-1})')
xlabel('Field (mT)')           

if app.savefig_CB_7.Value == 1
saveas(fig,app.figname_7.Value)
end 
           
close Figure 1
            
        end

        % Button pushed function: UpdateButton_6
        function UpdateButton_6Pushed(app, event)
            
            tempval = str2double(app.Switch_2.Value);


            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ao = str2double(app.ROIList2.Value);
            
            eval(['ROI = app.myStruct.ROI_' num2str(ao) '.mask;'])
            eval(['namet = app.myStruct.ROI_' num2str(ao) '.name;'])
            
            
            ROI = double(ROI);
            
            
            imagesc(app.UIAxes_ROI,ROI);
            colormap(app.UIAxes_ROI,gray)
            
            dim = size(app.myStruct.magimage);
            app.UIAxes_ROI.XLim = [0 dim(2)];
            app.UIAxes_ROI.YLim = [0 dim(1)];
            app.UIAxes_ROI.XTick = [];
            app.UIAxes_ROI.YTick = [];
            colormap(app.UIAxes_ROI,gray)
            
           figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            if app.savefig_CB_ROI.Value == 1
            savefig(app.figname_ROI.Value)
            end 
            
        end

        % Value changed function: ROIList2
        function ROIList2ValueChanged(app, event)
            
            ao = str2double(app.ROIList2.Value);
            
            eval(['namet = app.myStruct.ROI_' num2str(ao) '.name;'])
            
            app.figname_ROI.Value = [namet '.fig'];
            
        end

        % Button pushed function: fitallclusters_4
        function fitallclusters_4ButtonPushed(app, event)
            
           
            mkdir(app.foldername_2.Value)
                
            CiD =  app.myStruct.ROI.fit.A';   
           
            R1_in = app.myStruct.ROI.fit.tR1;
            R1d_in = app.myStruct.ROI.fit.tdR1;
            
            R1_in(R1_in(:,1)==0,:) = [];
            R1d_in(R1d_in(:,1)==0,:) = [];
            
            for px = 1:length(app.myStruct.fieldpoints)
%             
            eval(['R1_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1_in(:,px);']);
            eval(['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ' = R1d_in(:,px);']);
            str1{1,px} = ['R1_' num2str(round(app.myStruct.fieldpoints(px))) ','];    
            str2{1,px} = ['R1_d_' num2str(round(app.myStruct.fieldpoints(px))) ','];
            
            end
          
            Rsquare = app.myStruct.ROI.fit.tres';
            Rsquare(Rsquare(:,1)==0,:) = [];
            
            newStr1 = join(str1);
            newStr2 = join(str2);
            
            temp = ['CiD,' newStr1 newStr2 ' Rsquare'];
            temp2 = join(temp);
            temp2 = char(temp2);
            
            
           eval([ 'T = table( ' temp2 ');']);
           
           
           filename = [app.foldername_2.Value '/' app.excelname_2.Value];
           writetable(T,filename,'Sheet',1)
      
           ROIfit = app.myStruct;
     
           save([app.foldername_2.Value '/' 'ROI_fit'],'ROIfit')
           
           indx  = app.myStruct.ROI.fit.indx;


           for bx = 1:length(indx)
               
               if isempty(indx{bx}) ~= 1
                
               val = indx{1,bx};


              eval(['ROI_' num2str(val) '= app.myStruct.ROI_' num2str(val) '.mask'])
              eval(['filename = app.myStruct.ROI_' num2str(val) '.name' ])
              
              save([app.foldername_2.Value '/' filename '.mat'],['ROI_' num2str(val)])
              
%                eval(['name = app.myStruct.ROI_' num2str(val) '.name;']) 
%                eval([namet ' = app.myStruct.ROI_' num2str(val) '.mask;']) 
%                
%                save([app.foldername_2.Value '/' name],'namet')
               
               end 
               
           end
           
        end

        % Button pushed function: UpdateButton_8
        function UpdateButton_8Pushed(app, event)
          
                struct = app.myStruct.ROI.fit;
                indx = str2double(app.ROIlist.Value);
                B0 = struct.B0;
                R1 = struct.tR1(indx,:);
                gamma_in = struct.tgamma(indx);
                gammad_in = struct.tgammad(indx);
                noise_in = struct.tnoise(indx);
                B0pol = struct.B0_pol;
                data = squeeze(struct.datain{1,indx});
                t = struct.time(indx).a;
                name = struct.name{1,indx};
                app.r2_2.Value = struct.tres(indx);
           
   fig = figure;

if length(B0) > 3
tiledlayout(ceil(length(B0)/2),ceil(length(B0)/2))
else 
tiledlayout(1,ceil(length(B0)))
end 

 B0 = B0./1000;
 
for bx = 1:length(B0)
    
nexttile
          
R1_in = R1(bx);
B0_in = B0(bx);

dimt = size(t);

yp(:) = data(:,bx);

wp = logical(app.myStruct.outliers);

wp2 = reshape(wp,[dimt(1),dimt(2)]);

t(wp2 == 0) = NaN; 

tt =  t(bx,:); 

t_in = 0:0.002:max(t(:));

if app.autoscale_CB_4.Value == 0

    t_in = 0:0.002:app.xmax_2.Value;
    
end

    f = model_fit(R1_in,gamma_in,gammad_in,B0_in,noise_in,t_in,B0pol);


    plot(t_in,f,'r','LineWidth',4); hold on
    scatter(tt,yp,45,'k','markerfacecolor','k')
    
    set(gca,'FontSize', 10,'fontweight','bold','Linewidth',1.5)
    xlabel({'T_e_v (s)'},'FontSize', 14)
    ylabel({'Signal'}, 'FontSize', 14)
    title([num2str(B0_in*1000) ' (mT)'],'FontSize', 14)
    
    if app.autoscale_CB_4.Value == 0
    
    ylim([app.ymin_4.Value,app.ymax_4.Value])
    xlim([app.xmin_4.Value, app.xmax_4.Value])
    
end
     
     if bx == length(B0)
        legend(name)
     end
      
end

if app.savefig_CB_8.Value == 1
    
    saveas(fig,app.figname_8.Value)
    
end


if length(B0) > 3
pp = tiledlayout(app.Panel_19,ceil(length(B0)/2),ceil(length(B0)/2));
else 
pp = tiledlayout(app.Panel_19,ceil(length(B0)/2),ceil(length(B0)/2));
end 

% Tile 1


for bx = 1:length(B0)
    
eval(['ax' num2str(bx) '= nexttile(pp)']);

eval(['jx = ax' num2str(bx)])
          
R1_in = R1(bx);
B0_in = B0(bx);

tt =  t(bx,:);   
yp(:) = data(:,bx);

t_in = 0:0.002:max(t(:));

if app.autoscale_CB_4.Value == 0

    t_in = 0:0.002:app.xmax_4.Value;
    
end

    f = model_fit(R1_in,gamma_in,gammad_in,B0_in,noise_in,t_in,B0pol);


    plot(jx,t_in,f,'r','LineWidth',2); hold(jx)
    scatter(jx,tt,yp,20,'k','markerfacecolor','k')
    
%  jx.XLabel.String = 'Time (s)';
%  jx.YLabel.String = 'Signal';
 jx.Title.String = [num2str(B0_in*1000) ' (mT)'];
 set(jx,'FontSize', 8,'fontweight','bold','Linewidth',1.5)
 if app.autoscale_CB_4.Value == 0
 jx.YLim = [app.ymin_4.Value,app.ymax_4.Value];
 jx.XLim = [app.xmin_4.Value, app.xmax_4.Value];
 end
 
  if app.autoscale_CB_4.Value == 1
  jx.XLimMode = 'auto';
  jx.YLimMode = 'auto'; 
  end 
    
 
end

        end

        % Value changed function: cluster_option_plot
        function cluster_option_plotValueChanged(app, event)
            value = app.cluster_option_plot.Value;
            
            app.figname_2.Value = ['Cluster_' value '.fig'];
            
            
        end

        % Value changed function: cluster_option_plot_2
        function cluster_option_plot_2ValueChanged(app, event)
            value = app.cluster_option_plot_2.Value;
            
            app.figname_3.Value = ['C_' value '.fig'];
        end

        % Button pushed function: LoadButton_ROI1
        function LoadButton_ROI1Pushed(app, event)
            
            [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
            fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
           
            app.myStruct.ROI_1.mask = maskout; 
            app.myStruct.ROI_1.name = app.ROI1_name.Value; 
            
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ROI = double(maskout);
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            
        end

        % Button pushed function: LoadButton_ROI2
        function LoadButton_ROI2Pushed(app, event)
              [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
        fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
            
            app.myStruct.ROI_2.mask = maskout; 
            app.myStruct.ROI_2.name = app.ROI2_name.Value; 
            
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ROI = double(maskout);
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            
        end

        % Button pushed function: LoadButton_ROI3
        function LoadButton_ROI3Pushed(app, event)
            
             [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
           fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
            
            app.myStruct.ROI_3.mask = maskout; 
            app.myStruct.ROI_3.name = app.ROI3_name.Value; 
            
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ROI = double(maskout);
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';


        end

        % Button pushed function: LoadButton_ROI4
        function LoadButton_ROI4Pushed(app, event)
            
       [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
         fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
            
            app.myStruct.ROI_4.mask = maskout; 
            app.myStruct.ROI_4.name = app.ROI4_name.Value; 
            
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ROI = double(maskout);
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            
        end

        % Button pushed function: LoadButton_ROI5
        function LoadButton_ROI5Pushed(app, event)
              [Filename,PathName,FilterIndex] = uigetfile('.mat');
            
            maskt = load([PathName,'/' Filename]);
            
               fields = fieldnames(maskt);
            
            eval(['maskout = maskt.' fields{1,1} ';'])
            
            
            app.myStruct.ROI_5.mask = maskout; 
            app.myStruct.ROI_5.name = app.ROI5_name.Value; 
            
            tempval = str2double(app.Switch_2.Value);
            
            if tempval == 1  
                img_int = abs(app.myStruct.compleximage);   
            end
             
            if tempval == 0    
                img_int = app.myStruct.magimage;  
            end
            
            timevalue = str2double(app.TimeDropDown.Value);
            fieldvalue = str2double(app.FieldDropDown.Value);
          
            kf = find(round(app.myStruct.fieldpoints,3) == round(fieldvalue,3));
            at = app.myStruct.timepoints(kf,:);
            kt = find(round(at,3) == round(timevalue,3));
            
            img_in(:,:) = img_int(:,:,1,kt,kf);
            
            ROI = double(maskout);
            
            figure
            
            a1 = axes;
            h1 = imagesc(a1,img_in.*app.myStruct.mask); axis image
            
            colormap(a1,'gray'); axis image;
            
            ROI(ROI == 0) = NaN;
            
            a2 = axes;
            h2 = imagesc(a2,ROI); caxis(a1,[app.minSlider.Value,app.maxSlider.Value])
            colormap(a2,'autumn'); axis image; caxis(a2,[0 8])
            
            
            alpha(h2,isfinite(ROI)*0.5);
            
            a1.Visible = 'off';
            a2.Visible = 'off';
            
            
        end

        % Value changed function: autoscale_CB_3
        function autoscale_CB_3ValueChanged(app, event)
            value = app.autoscale_CB_3.Value;
            
            if value == 0
            
            app.xmin_3.Enable = 1;
            app.xmax_3.Enable = 1;
            app.ymin_3.Enable = 1;
            app.ymax_3.Enable = 1;
            
            end
            
            if value == 1
            
            app.xmin_3.Enable = 0;
            app.xmax_3.Enable = 0;
            app.ymin_3.Enable = 0;
            app.ymax_3.Enable = 0;
            
            end
          
        end

        % Value changed function: autoscale_CB_4
        function autoscale_CB_4ValueChanged(app, event)
            value = app.autoscale_CB_4.Value;
            
                      
            if value == 0
            
            app.xmin_4.Enable = 1;
            app.xmax_4.Enable = 1;
            app.ymin_4.Enable = 1;
            app.ymax_4.Enable = 1;
            
            end
            
            if value == 1
            
            app.xmin_4.Enable = 0;
            app.xmax_4.Enable = 0;
            app.ymin_4.Enable = 0;
            app.ymax_4.Enable = 0;
            
            end
            
        end

        % Value changed function: savefig_CB_7
        function savefig_CB_7ValueChanged(app, event)
            value = app.savefig_CB_7.Value;
            
            if value == 1
            app.figname_7.Enable = 1;
            end
            
            
            if value == 0
            app.figname_7.Enable = 0;
            end
            
        end

        % Value changed function: savefig_CB_8
        function savefig_CB_8ValueChanged(app, event)
            value = app.savefig_CB_8.Value;
            
              if value == 1
            app.figname_8.Enable = 1;
            end
            
            
            if value == 0
            app.figname_8.Enable = 0;
            end
            
            
        end

        % Value changed function: savefig_CB_2
        function savefig_CB_2ValueChanged(app, event)
            value = app.savefig_CB_2.Value;
            
              if value == 1
            app.figname_2.Enable = 1;
            end
            
            if value == 0
            app.figname_2.Enable = 0;
            end


        end

        % Value changed function: savefig_CB_3
        function savefig_CB_3ValueChanged(app, event)
            value = app.savefig_CB_3.Value;
            
            if value == 1
            app.figname_3.Enable = 1;
            end
            
            if value == 0
            app.figname_3.Enable = 0;
            end
            
        end

        % Value changed function: savefig_CB_4
        function savefig_CB_4ValueChanged(app, event)
            value = app.savefig_CB_4.Value;
            
                  if value == 1
            app.figname_4.Enable = 1;
            end
            
            if value == 0
            app.figname_4.Enable = 0;
            end
            
            
        end

        % Button pushed function: UpdateoutlierremovalButton
        function UpdateoutlierremovalButtonPushed(app, event)
            
           app.myStruct.outliers = app.UITable_3.Data(:,3);
          
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            
            value = str2double(value);
            
            if value == 1
                
                app.CircleradiusLabel.Enable = 1;
                app.voxelsLabel_3.Enable = 1;
                app.circlesize.Enable = 1;
                
            end
                
            if value == 0
                
                app.CircleradiusLabel.Enable = 0;
                app.voxelsLabel_3.Enable = 0;
                app.circlesize.Enable = 0;
                
            end
        end

        % Value changed function: ROIlist
        function ROIlistValueChanged(app, event)
            value = app.ROIlist.Value;
            
            name = app.myStruct.ROI.fit.name{1,str2double(value)}; 
            
            app.figname_8.Value = [name 'Fit.fig'];


        end

        % Button pushed function: Set1
        function Set1ButtonPushed(app, event)
            
            px = 1;
                       
            app.myStruct.R1_guess(px,:) = app.UITable_2.Data(:,2)';
            app.myStruct.Noise_guess(px,:) = app.noise_2.Value;
            
            app.Lamp1.Enable = 1;
            
        end

        % Button pushed function: Set2
        function Set2ButtonPushed(app, event)
                  px = 2;
            
            app.myStruct.R1_guess(px,:) = app.UITable_2.Data(:,2)';
            app.myStruct.Noise_guess(px,:) = app.noise_2.Value;
            
            app.Lamp2.Enable = 1;
        end

        % Button pushed function: Set3
        function Set3ButtonPushed(app, event)
                  px = 3;
            
            app.myStruct.R1_guess(px,:) = app.UITable_2.Data(:,2)';
            app.myStruct.Noise_guess(px,:) = app.noise_2.Value;
            
            app.Lamp3.Enable = 1;
        end

        % Button pushed function: Set4
        function Set4ButtonPushed(app, event)
                           px = 4;
          
            app.myStruct.R1_guess(px,:) = app.UITable_2.Data(:,2)';
            app.myStruct.Noise_guess(px,:) = app.noise_2.Value;
            
            app.Lamp4.Enable = 1;
        end

        % Button pushed function: Set5
        function Set5ButtonPushed(app, event)
            px = 5;
            
            app.myStruct.R1_guess(px,:) = app.UITable_2.Data(:,2)';
            app.myStruct.Noise_guess(px,:) = app.noise_2.Value;
            
            app.Lamp5.Enable = 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.myStruct.R1_guess(:,:) = NaN;
            app.myStruct.Noise_guess(:,:) = NaN;
            
            app.Lamp1.Enable = 0;
            app.Lamp2.Enable = 0;
            app.Lamp3.Enable = 0;
            app.Lamp4.Enable = 0;
            app.Lamp5.Enable = 0;
            
            
        end

        % Value changed function: CB1_2
        function CB1_2ValueChanged(app, event)
            value = app.CB1_2.Value;
            
            if value == 1 
                app.Set1.Enable = 1;
            else 
                app.Set1.Enable = 0;
            end
            
        end

        % Value changed function: CB2_2
        function CB2_2ValueChanged(app, event)
            value = app.CB2_2.Value;
            
            if value == 1 
                app.Set2.Enable = 1;
            else 
                app.Set2.Enable = 0;
            end
            
        end

        % Value changed function: CB3_2
        function CB3_2ValueChanged(app, event)
            value = app.CB3_2.Value;
            
               
            if value == 1 
                app.Set3.Enable = 1;
            else 
                app.Set3.Enable = 0;
            end
            
        end

        % Value changed function: CB4_2
        function CB4_2ValueChanged(app, event)
            value = app.CB4_2.Value;
            
               
            if value == 1 
                app.Set4.Enable = 1;
            else 
                app.Set4.Enable = 0;
            end
            
        end

        % Value changed function: CB5_2
        function CB5_2ValueChanged(app, event)
            value = app.CB5_2.Value;
            
            if value == 1 
                app.Set5.Enable = 1;
            else 
                app.Set5.Enable = 0;
            end
            
        end

        % Size changed function: AllclustersTab
        function AllclustersTabSizeChanged(app, event)
        
        end

        % Size changed function: DefineclustersTab
        function DefineclustersTabSizeChanged(app, event)
           
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            value = app.CB1.Value;
            
               
            if value == 1 
                app.Set1_2.Enable = 1;
            else 
                app.Set1_2.Enable = 0;
            end
            
            
        end

        % Value changed function: CB2
        function CB2ValueChanged(app, event)
            value = app.CB2.Value;
            
               if value == 1 
                app.Set2_2.Enable = 1;
            else 
                app.Set2_2.Enable = 0;
            end
            
        end

        % Value changed function: CB3
        function CB3ValueChanged(app, event)
            value = app.CB3.Value;
            
               if value == 1 
                app.Set3_2.Enable = 1;
            else 
                app.Set3_2.Enable = 0;
            end
            
        end

        % Value changed function: CB4
        function CB4ValueChanged(app, event)
            value = app.CB4.Value;
            
               if value == 1 
                app.Set4_2.Enable = 1;
            else 
                app.Set4_2.Enable = 0;
            end
            
        end

        % Value changed function: CB5
        function CB5ValueChanged(app, event)
            value = app.CB5.Value;
            
              if value == 1 
                app.Set5_2.Enable = 1;
            else 
                app.Set5_2.Enable = 0;
            end
            
            
        end

        % Button pushed function: Set1_2
        function Set1_2ButtonPushed(app, event)
            
              
            px = 1;
                       
            app.myStruct.R1_guess_2(px,:) = app.UITable_4.Data(:,2)';
            app.myStruct.Noise_guess_2(px,:) = app.noise_3.Value;
            
            app.Lamp1_2.Enable = 1;
            
        end

        % Button pushed function: Set2_2
        function Set2_2ButtonPushed(app, event)
            
             px = 2;
                       
            app.myStruct.R1_guess_2(px,:) = app.UITable_4.Data(:,2)';
            app.myStruct.Noise_guess_2(px,:) = app.noise_3.Value;
            
            app.Lamp2_2.Enable = 1;
            
        end

        % Button pushed function: Set3_2
        function Set3_2ButtonPushed(app, event)
            
             px = 3;
                       
            app.myStruct.R1_guess_2(px,:) = app.UITable_4.Data(:,2)';
            app.myStruct.Noise_guess_2(px,:) = app.noise_3.Value;
            
            app.Lamp3_2.Enable = 1;
            
        end

        % Button pushed function: Set4_2
        function Set4_2ButtonPushed(app, event)
            
            px = 4;
                       
            app.myStruct.R1_guess_2(px,:) = app.UITable_4.Data(:,2)';
            app.myStruct.Noise_guess_2(px,:) = app.noise_3.Value;
            
            app.Lamp4_2.Enable = 1;
            
            
        end

        % Button pushed function: Set5_2
        function Set5_2ButtonPushed(app, event)
            
            px = 5;
                       
            app.myStruct.R1_guess_2(px,:) = app.UITable_4.Data(:,2)';
            app.myStruct.Noise_guess_2(px,:) = app.noise_3.Value;
            
            app.Lamp5_2.Enable = 1;
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            
            app.myStruct.R1_guess_2(:,:) = NaN;
            app.myStruct.Noise_guess_2(:,:) = NaN;
            
            app.Lamp1_2.Enable = 0;
            app.Lamp2_2.Enable = 0;
            app.Lamp3_2.Enable = 0;
            app.Lamp4_2.Enable = 0;
            app.Lamp5_2.Enable = 0;
            
            
        end

        % Selection change function: TabGroup6
        function TabGroup6SelectionChanged(app, event)
            selectedTab = app.TabGroup6.SelectedTab;
            
            a = 1; 
            
       if  strcmp(selectedTab.Title,'All clusters') == true
            
            app.Set1_2.Enable = 0;
            app.Set2_2.Enable = 0;
            app.Set3_2.Enable = 0;
            app.Set4_2.Enable = 0;
            app.Set5_2.Enable = 0;
            
            app.Lamp1_2.Enable = 0;
            app.Lamp2_2.Enable = 0;
            app.Lamp3_2.Enable = 0;
            app.Lamp4_2.Enable = 0;
            app.Lamp5_2.Enable = 0;
            
            app.CB1.Value = 0;
            app.CB2.Value = 0;
            app.CB3.Value = 0;
            app.CB4.Value = 0;
            app.CB5.Value = 0;
            
       end
         
        end

        % Value changed function: median_mean
        function median_meanValueChanged(app, event)
            value = app.median_mean.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel_14 = uipanel(app.UIFigure);
            app.ImagedisplayPanel = uipanel(app.Panel_14);
            app.ImagedisplayPanel.Title = 'Image display';
            app.Panel_10 = uipanel(app.ImagedisplayPanel);
            app.FieldmTLabel = uilabel(app.Panel_10);
            app.FieldmTLabel.Text = 'Field (mT)';
            app.EvolutiontimemsLabel = uilabel(app.Panel_10);
            app.EvolutiontimemsLabel.Text = 'Evolution time (ms)';
            app.TimeDropDown = uidropdown(app.Panel_10);
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.FieldDropDown = uidropdown(app.Panel_10);
            app.FieldDropDown.Editable = 'on';
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.minvalue = uieditfield(app.Panel_10, 'numeric');
            app.minvalue.ValueChangedFcn = createCallbackFcn(app, @minvalueValueChanged, true);
            app.minSliderLabel = uilabel(app.Panel_10);
            app.minSliderLabel.Text = 'min';
            app.minSlider = uislider(app.Panel_10);
            app.minSlider.MajorTicks = [];
            app.minSlider.MajorTickLabels = {};
            app.minSlider.ValueChangedFcn = createCallbackFcn(app, @minSliderValueChanged, true);
            app.minSlider.MinorTicks = [];
            app.WindowLabel = uilabel(app.Panel_10);
            app.WindowLabel.Text = 'Window';
            app.maxSliderLabel = uilabel(app.Panel_10);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.Panel_10);
            app.maxSlider.MajorTicks = [];
            app.maxSlider.MajorTickLabels = {};
            app.maxSlider.ValueChangedFcn = createCallbackFcn(app, @maxSliderValueChanged, true);
            app.maxSlider.MinorTicks = [];
            app.maxvalue = uieditfield(app.Panel_10, 'numeric');
            app.maxvalue.ValueChangedFcn = createCallbackFcn(app, @maxvalueValueChanged, true);
            app.ResetwindowButton = uibutton(app.Panel_10, 'push');
            app.ResetwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ResetwindowButtonPushed, true);
            app.ResetwindowButton.Text = 'Reset window';
            app.Switch = uiswitch(app.Panel_10, 'slider');
            app.Switch.Items = {'Complex', 'Magnitude image'};
            app.Switch.ItemsData = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '1';
            app.Panel_11 = uipanel(app.ImagedisplayPanel);
            app.UIAxes = uiaxes(app.Panel_11);
            app.TabGroup = uitabgroup(app.Panel_14);
            app.DrawmaskTab = uitab(app.TabGroup);
            app.DrawmaskTab.Title = 'Draw mask';
            app.UIAxes_2 = uiaxes(app.DrawmaskTab);
            app.Panel_12 = uipanel(app.DrawmaskTab);
            app.noofregionsEditFieldLabel = uilabel(app.Panel_12);
            app.noofregionsEditFieldLabel.Text = 'no. of regions';
            app.noofregionsEditField = uieditfield(app.Panel_12, 'numeric');
            app.noofregionsEditField.Value = 1;
            app.DrawButton = uibutton(app.Panel_12, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.maskname_2 = uieditfield(app.Panel_12, 'text');
            app.maskname_2.Value = 'mask.mat';
            app.SaveButton_2 = uibutton(app.Panel_12, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.LoadmaskTab = uitab(app.TabGroup);
            app.LoadmaskTab.Title = 'Load mask';
            app.UIAxes_10 = uiaxes(app.LoadmaskTab);
            app.Panel_13 = uipanel(app.LoadmaskTab);
            app.LoadButton_2 = uibutton(app.Panel_13, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.RemoveoutliersTab = uitab(app.TabGroup);
            app.RemoveoutliersTab.Title = 'Remove outliers';
            app.UITable_3 = uitable(app.RemoveoutliersTab);
            app.UITable_3.ColumnName = {'Field (mT)'; 'Time (ms)'; 'Retain (1) or Remove (0)'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = [false false true];
            app.UpdateoutlierremovalButton = uibutton(app.RemoveoutliersTab, 'push');
            app.UpdateoutlierremovalButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateoutlierremovalButtonPushed, true);
            app.UpdateoutlierremovalButton.Text = 'Update outlier removal';
            app.TabGroup2 = uitabgroup(app.Panel_14);
            app.ROIdrawTab = uitab(app.TabGroup2);
            app.ROIdrawTab.Title = 'ROI draw';
            app.TabGroup4_2 = uitabgroup(app.ROIdrawTab);
            app.ViewregionTab = uitab(app.TabGroup4_2);
            app.ViewregionTab.Title = 'View region';
            app.UIAxes_ROI = uiaxes(app.ViewregionTab);
            app.ROIList2 = uidropdown(app.ViewregionTab);
            app.ROIList2.Items = {'-'};
            app.ROIList2.Editable = 'on';
            app.ROIList2.ValueChangedFcn = createCallbackFcn(app, @ROIList2ValueChanged, true);
            app.ROIList2.Value = '-';
            app.savefig_CB_ROI = uicheckbox(app.ViewregionTab);
            app.savefig_CB_ROI.Text = '';
            app.SavefigureLabel_6 = uilabel(app.ViewregionTab);
            app.SavefigureLabel_6.Text = 'Save figure';
            app.figname_ROI = uieditfield(app.ViewregionTab, 'text');
            app.figname_ROI.Enable = 'off';
            app.figname_ROI.Value = 'cluster.fig';
            app.UpdateButton_6 = uibutton(app.ViewregionTab, 'push');
            app.UpdateButton_6.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_6Pushed, true);
            app.UpdateButton_6.Text = 'Update';
            app.NMRDFittingPanel_2 = uipanel(app.ROIdrawTab);
            app.NMRDFittingPanel_2.Title = 'NMRD Fitting';
            app.Label_12 = uilabel(app.NMRDFittingPanel_2);
            app.Label_12.Text = '';
            app.Panel_18 = uipanel(app.NMRDFittingPanel_2);
            app.Label_13 = uilabel(app.Panel_18);
            app.Label_13.Text = '1';
            app.Label_14 = uilabel(app.Panel_18);
            app.Label_14.Text = '2';
            app.Label_15 = uilabel(app.Panel_18);
            app.Label_15.Text = '3';
            app.Label_16 = uilabel(app.Panel_18);
            app.Label_16.Text = '4';
            app.Label_17 = uilabel(app.Panel_18);
            app.Label_17.Text = '5';
            app.NameLabel_2 = uilabel(app.Panel_18);
            app.NameLabel_2.Text = 'Name';
            app.FitLabel_2 = uilabel(app.Panel_18);
            app.FitLabel_2.Text = 'Fit';
            app.ROI1_name = uieditfield(app.Panel_18, 'text');
            app.ROI1_name.Value = 'Tissue1';
            app.ROI2_name = uieditfield(app.Panel_18, 'text');
            app.ROI2_name.Value = 'Tissue2';
            app.ROI3_name = uieditfield(app.Panel_18, 'text');
            app.ROI3_name.Value = 'Tissue3';
            app.ROI4_name = uieditfield(app.Panel_18, 'text');
            app.ROI4_name.Value = 'Tissue4';
            app.ROI5_name = uieditfield(app.Panel_18, 'text');
            app.ROI5_name.Value = 'Tissue5';
            app.CB1_2 = uicheckbox(app.Panel_18);
            app.CB1_2.ValueChangedFcn = createCallbackFcn(app, @CB1_2ValueChanged, true);
            app.CB1_2.Text = '';
            app.CB1_2.Value = true;
            app.CB2_2 = uicheckbox(app.Panel_18);
            app.CB2_2.ValueChangedFcn = createCallbackFcn(app, @CB2_2ValueChanged, true);
            app.CB2_2.Text = '';
            app.CB3_2 = uicheckbox(app.Panel_18);
            app.CB3_2.ValueChangedFcn = createCallbackFcn(app, @CB3_2ValueChanged, true);
            app.CB3_2.Text = '';
            app.CB4_2 = uicheckbox(app.Panel_18);
            app.CB4_2.ValueChangedFcn = createCallbackFcn(app, @CB4_2ValueChanged, true);
            app.CB4_2.Text = '';
            app.CB5_2 = uicheckbox(app.Panel_18);
            app.CB5_2.ValueChangedFcn = createCallbackFcn(app, @CB5_2ValueChanged, true);
            app.CB5_2.Text = '';
            app.IndxLabel_3 = uilabel(app.Panel_18);
            app.IndxLabel_3.Text = 'Indx';
            app.DrawLoadLabel = uilabel(app.Panel_18);
            app.DrawLoadLabel.Text = 'Draw / Load';
            app.DrawButton_1 = uibutton(app.Panel_18, 'push');
            app.DrawButton_1.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_1Pushed, true);
            app.DrawButton_1.Text = 'Draw';
            app.DrawButton_2 = uibutton(app.Panel_18, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.DrawButton_3 = uibutton(app.Panel_18, 'push');
            app.DrawButton_3.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_3Pushed, true);
            app.DrawButton_3.Text = 'Draw';
            app.DrawButton_4 = uibutton(app.Panel_18, 'push');
            app.DrawButton_4.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_4Pushed, true);
            app.DrawButton_4.Text = 'Draw';
            app.DrawButton_5 = uibutton(app.Panel_18, 'push');
            app.DrawButton_5.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_5Pushed, true);
            app.DrawButton_5.Text = 'Draw';
            app.Switch_2 = uiswitch(app.Panel_18, 'slider');
            app.Switch_2.Items = {'Complex', 'Magnitude image'};
            app.Switch_2.ItemsData = {'1', '0'};
            app.Switch_2.Value = '1';
            app.LoadButton_ROI1 = uibutton(app.Panel_18, 'push');
            app.LoadButton_ROI1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ROI1Pushed, true);
            app.LoadButton_ROI1.Text = 'Load';
            app.LoadButton_ROI2 = uibutton(app.Panel_18, 'push');
            app.LoadButton_ROI2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ROI2Pushed, true);
            app.LoadButton_ROI2.Text = 'Load';
            app.LoadButton_ROI3 = uibutton(app.Panel_18, 'push');
            app.LoadButton_ROI3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ROI3Pushed, true);
            app.LoadButton_ROI3.Text = 'Load';
            app.LoadButton_ROI4 = uibutton(app.Panel_18, 'push');
            app.LoadButton_ROI4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ROI4Pushed, true);
            app.LoadButton_ROI4.Text = 'Load';
            app.LoadButton_ROI5 = uibutton(app.Panel_18, 'push');
            app.LoadButton_ROI5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ROI5Pushed, true);
            app.LoadButton_ROI5.Text = 'Load';
            app.Switch_3 = uiswitch(app.Panel_18, 'slider');
            app.Switch_3.Items = {'Circle', 'Freehand'};
            app.Switch_3.ItemsData = {'1', '0'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '1';
            app.circlesize = uieditfield(app.Panel_18, 'numeric');
            app.circlesize.Value = 2;
            app.CircleradiusLabel = uilabel(app.Panel_18);
            app.CircleradiusLabel.Text = 'Circle radius';
            app.voxelsLabel_3 = uilabel(app.Panel_18);
            app.voxelsLabel_3.Text = 'voxels';
            app.median_mean = uiswitch(app.Panel_18, 'slider');
            app.median_mean.Items = {'Median', 'Mean'};
            app.median_mean.ItemsData = {'1', '0'};
            app.median_mean.ValueChangedFcn = createCallbackFcn(app, @median_meanValueChanged, true);
            app.median_mean.Value = '1';
            app.ROIaverageasLabel = uilabel(app.Panel_18);
            app.ROIaverageasLabel.Text = 'ROI average as';
            app.RunfitButton_2 = uibutton(app.NMRDFittingPanel_2, 'push');
            app.RunfitButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunfitButton_2Pushed, true);
            app.RunfitButton_2.Text = 'Run fit';
            app.RunningLamp_3Label = uilabel(app.NMRDFittingPanel_2);
            app.RunningLamp_3Label.Text = 'Running';
            app.RunningLamp_3 = uilamp(app.NMRDFittingPanel_2);
            app.RunningLamp_3.Enable = 'off';
            app.Panel_20 = uipanel(app.NMRDFittingPanel_2);
            app.UITable_2 = uitable(app.Panel_20);
            app.UITable_2.ColumnName = {'Field (mT)'; 'R1 Guess (1/s)'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true];
            app.R1initialguessLabel_2 = uilabel(app.Panel_20);
            app.R1initialguessLabel_2.Text = 'R1 initial guess';
            app.noise_2 = uieditfield(app.Panel_20, 'numeric');
            app.noise_2.Value = 0.1;
            app.NoisefactorLabel_2 = uilabel(app.Panel_20);
            app.NoisefactorLabel_2.Text = 'Noise factor';
            app.ofsignalLabel_2 = uilabel(app.Panel_20);
            app.ofsignalLabel_2.Text = '% of signal';
            app.Label_23 = uilabel(app.Panel_20);
            app.Label_23.Text = '1';
            app.Label_24 = uilabel(app.Panel_20);
            app.Label_24.Text = '2';
            app.Label_25 = uilabel(app.Panel_20);
            app.Label_25.Text = '3';
            app.Label_26 = uilabel(app.Panel_20);
            app.Label_26.Text = '4';
            app.Label_27 = uilabel(app.Panel_20);
            app.Label_27.Text = '5';
            app.IndxLabel_5 = uilabel(app.Panel_20);
            app.IndxLabel_5.Text = 'Indx';
            app.GuessLabel = uilabel(app.Panel_20);
            app.GuessLabel.Text = 'Guess';
            app.Set1 = uibutton(app.Panel_20, 'push');
            app.Set1.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1.Text = 'Set';
            app.Set2 = uibutton(app.Panel_20, 'push');
            app.Set2.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2.Enable = 'off';
            app.Set2.Text = 'Set';
            app.Set3 = uibutton(app.Panel_20, 'push');
            app.Set3.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3.Enable = 'off';
            app.Set3.Text = 'Set';
            app.Set4 = uibutton(app.Panel_20, 'push');
            app.Set4.ButtonPushedFcn = createCallbackFcn(app, @Set4ButtonPushed, true);
            app.Set4.Enable = 'off';
            app.Set4.Text = 'Set';
            app.Set5 = uibutton(app.Panel_20, 'push');
            app.Set5.ButtonPushedFcn = createCallbackFcn(app, @Set5ButtonPushed, true);
            app.Set5.Enable = 'off';
            app.Set5.Text = 'Set';
            app.Lamp1 = uilamp(app.Panel_20);
            app.Lamp1.Enable = 'off';
            app.Lamp2 = uilamp(app.Panel_20);
            app.Lamp2.Enable = 'off';
            app.Lamp3 = uilamp(app.Panel_20);
            app.Lamp3.Enable = 'off';
            app.Lamp4 = uilamp(app.Panel_20);
            app.Lamp4.Enable = 'off';
            app.Lamp5 = uilamp(app.Panel_20);
            app.Lamp5.Enable = 'off';
            app.ResetButton = uibutton(app.Panel_20, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup5_2 = uitabgroup(app.ROIdrawTab);
            app.NMRDTab_2 = uitab(app.TabGroup5_2);
            app.NMRDTab_2.Title = 'NMRD';
            app.UIAxes_11 = uiaxes(app.NMRDTab_2);
            app.UIAxes_11.XTick = [0 0.5 1];
            app.TabGroup7_2 = uitabgroup(app.NMRDTab_2);
            app.ROITab = uitab(app.TabGroup7_2);
            app.ROITab.Title = 'ROI';
            app.IndxLabel_4 = uilabel(app.ROITab);
            app.IndxLabel_4.Text = 'Indx';
            app.SelectLabel_2 = uilabel(app.ROITab);
            app.SelectLabel_2.Text = 'Select';
            app.A1_2 = uicheckbox(app.ROITab);
            app.A1_2.Text = '';
            app.A2_2 = uicheckbox(app.ROITab);
            app.A2_2.Text = '';
            app.A3_2 = uicheckbox(app.ROITab);
            app.A3_2.Text = '';
            app.A4_2 = uicheckbox(app.ROITab);
            app.A4_2.Text = '';
            app.A5_2 = uicheckbox(app.ROITab);
            app.A5_2.Text = '';
            app.Label_18 = uilabel(app.ROITab);
            app.Label_18.Text = '1';
            app.Label_19 = uilabel(app.ROITab);
            app.Label_19.Text = '2';
            app.Label_20 = uilabel(app.ROITab);
            app.Label_20.Text = '3';
            app.Label_21 = uilabel(app.ROITab);
            app.Label_21.Text = '4';
            app.Label_22 = uilabel(app.ROITab);
            app.Label_22.Text = '5';
            app.LogscalexLabel_2 = uilabel(app.NMRDTab_2);
            app.LogscalexLabel_2.Text = 'Log scale x';
            app.LogscaleyLabel_2 = uilabel(app.NMRDTab_2);
            app.LogscaleyLabel_2.Text = 'Log scale y';
            app.xlog_CB_2 = uicheckbox(app.NMRDTab_2);
            app.xlog_CB_2.Text = '';
            app.ylog_CB_2 = uicheckbox(app.NMRDTab_2);
            app.ylog_CB_2.Text = '';
            app.xminLabel_3 = uilabel(app.NMRDTab_2);
            app.xminLabel_3.Text = 'x min';
            app.xmaxLabel_3 = uilabel(app.NMRDTab_2);
            app.xmaxLabel_3.Text = 'x max';
            app.yminLabel_3 = uilabel(app.NMRDTab_2);
            app.yminLabel_3.Text = 'y min';
            app.ymaxLabel_3 = uilabel(app.NMRDTab_2);
            app.ymaxLabel_3.Text = 'y max';
            app.ErrorbarsLabel_2 = uilabel(app.NMRDTab_2);
            app.ErrorbarsLabel_2.Text = 'Error bars';
            app.errorbar_CB_2 = uicheckbox(app.NMRDTab_2);
            app.errorbar_CB_2.Text = '';
            app.errorbar_CB_2.Value = true;
            app.AutoscaleLabel_3 = uilabel(app.NMRDTab_2);
            app.AutoscaleLabel_3.Text = 'Auto scale';
            app.autoscale_CB_3 = uicheckbox(app.NMRDTab_2);
            app.autoscale_CB_3.ValueChangedFcn = createCallbackFcn(app, @autoscale_CB_3ValueChanged, true);
            app.autoscale_CB_3.Text = '';
            app.autoscale_CB_3.Value = true;
            app.xmin_3 = uieditfield(app.NMRDTab_2, 'numeric');
            app.xmin_3.Enable = 'off';
            app.xmax_3 = uieditfield(app.NMRDTab_2, 'numeric');
            app.xmax_3.Enable = 'off';
            app.xmax_3.Value = 250;
            app.ymin_3 = uieditfield(app.NMRDTab_2, 'numeric');
            app.ymin_3.Enable = 'off';
            app.ymax_3 = uieditfield(app.NMRDTab_2, 'numeric');
            app.ymax_3.Enable = 'off';
            app.ymax_3.Value = 20;
            app.figname_7 = uieditfield(app.NMRDTab_2, 'text');
            app.figname_7.Enable = 'off';
            app.figname_7.Value = 'NMRD.fig';
            app.mTLabel_2 = uilabel(app.NMRDTab_2);
            app.mTLabel_2.Text = 'mT';
            app.sLabel_4 = uilabel(app.NMRDTab_2);
            app.sLabel_4.Text = '1/s';
            app.exportfigureonlyLabel_2 = uilabel(app.NMRDTab_2);
            app.exportfigureonlyLabel_2.Text = '(export figure only)';
            app.SavefigureLabel_7 = uilabel(app.NMRDTab_2);
            app.SavefigureLabel_7.Text = 'Save figure';
            app.savefig_CB_7 = uicheckbox(app.NMRDTab_2);
            app.savefig_CB_7.ValueChangedFcn = createCallbackFcn(app, @savefig_CB_7ValueChanged, true);
            app.savefig_CB_7.Text = '';
            app.UpdateButton_9 = uibutton(app.NMRDTab_2, 'push');
            app.UpdateButton_9.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_9Pushed, true);
            app.UpdateButton_9.Text = 'Update';
            app.FitTab_2 = uitab(app.TabGroup5_2);
            app.FitTab_2.Title = 'Fit';
            app.ROIlist = uidropdown(app.FitTab_2);
            app.ROIlist.Items = {'-'};
            app.ROIlist.Editable = 'on';
            app.ROIlist.ValueChangedFcn = createCallbackFcn(app, @ROIlistValueChanged, true);
            app.ROIlist.Value = '-';
            app.xminLabel_4 = uilabel(app.FitTab_2);
            app.xminLabel_4.Text = 'x min';
            app.xmaxLabel_4 = uilabel(app.FitTab_2);
            app.xmaxLabel_4.Text = 'x max';
            app.yminLabel_4 = uilabel(app.FitTab_2);
            app.yminLabel_4.Text = 'y min';
            app.ymaxLabel_4 = uilabel(app.FitTab_2);
            app.ymaxLabel_4.Text = 'y max';
            app.AutoscaleLabel_4 = uilabel(app.FitTab_2);
            app.AutoscaleLabel_4.Text = 'Auto scale';
            app.autoscale_CB_4 = uicheckbox(app.FitTab_2);
            app.autoscale_CB_4.ValueChangedFcn = createCallbackFcn(app, @autoscale_CB_4ValueChanged, true);
            app.autoscale_CB_4.Text = '';
            app.autoscale_CB_4.Value = true;
            app.xmin_4 = uieditfield(app.FitTab_2, 'numeric');
            app.xmin_4.Enable = 'off';
            app.xmax_4 = uieditfield(app.FitTab_2, 'numeric');
            app.xmax_4.Enable = 'off';
            app.xmax_4.Value = 4;
            app.ymin_4 = uieditfield(app.FitTab_2, 'numeric');
            app.ymin_4.Enable = 'off';
            app.ymax_4 = uieditfield(app.FitTab_2, 'numeric');
            app.ymax_4.Enable = 'off';
            app.ymax_4.Value = 10;
            app.sLabel_5 = uilabel(app.FitTab_2);
            app.sLabel_5.Text = 's';
            app.UpdateButton_8 = uibutton(app.FitTab_2, 'push');
            app.UpdateButton_8.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_8Pushed, true);
            app.UpdateButton_8.Text = 'Update';
            app.figname_8 = uieditfield(app.FitTab_2, 'text');
            app.figname_8.Enable = 'off';
            app.figname_8.Value = 'fit.fig';
            app.SavefigureLabel_8 = uilabel(app.FitTab_2);
            app.SavefigureLabel_8.Text = 'Save figure';
            app.savefig_CB_8 = uicheckbox(app.FitTab_2);
            app.savefig_CB_8.ValueChangedFcn = createCallbackFcn(app, @savefig_CB_8ValueChanged, true);
            app.savefig_CB_8.Text = '';
            app.ROILabel = uilabel(app.FitTab_2);
            app.ROILabel.Text = 'ROI';
            app.rsquaredLabel_2 = uilabel(app.FitTab_2);
            app.rsquaredLabel_2.Text = 'r squared';
            app.r2_2 = uieditfield(app.FitTab_2, 'numeric');
            app.r2_2.Editable = 'off';
            app.Panel_19 = uipanel(app.FitTab_2);
            app.Panel_16 = uipanel(app.ROIdrawTab);
            app.ROIFitLabel = uilabel(app.Panel_16);
            app.ROIFitLabel.Text = 'ROI Fit';
            app.FoldernameLabel_2 = uilabel(app.Panel_16);
            app.FoldernameLabel_2.Text = 'Folder name';
            app.foldername_2 = uieditfield(app.Panel_16, 'text');
            app.foldername_2.Value = 'ROI_fit';
            app.ExcelfilenameLabel_2 = uilabel(app.Panel_16);
            app.ExcelfilenameLabel_2.Text = 'Excel file name';
            app.excelname_2 = uieditfield(app.Panel_16, 'text');
            app.excelname_2.Value = 'ROI_fit.xlsx';
            app.fitallclusters_4 = uibutton(app.Panel_16, 'push');
            app.fitallclusters_4.ButtonPushedFcn = createCallbackFcn(app, @fitallclusters_4ButtonPushed, true);
            app.fitallclusters_4.Text = 'Save ';
            app.ClusterTab = uitab(app.TabGroup2);
            app.ClusterTab.Title = 'Cluster';
            app.HierarchicalclusteranalysisPanel = uipanel(app.ClusterTab);
            app.HierarchicalclusteranalysisPanel.Title = 'Hierarchical cluster analysis';
            app.Panel = uipanel(app.HierarchicalclusteranalysisPanel);
            app.threshold = uieditfield(app.Panel, 'numeric');
            app.threshold.ValueChangedFcn = createCallbackFcn(app, @thresholdValueChanged, true);
            app.threshold.Value = 1.15;
            app.SmoothingLabel = uilabel(app.Panel);
            app.SmoothingLabel.Text = 'Smoothing';
            app.Smoothing_CheckBox = uicheckbox(app.Panel);
            app.Smoothing_CheckBox.Text = '';
            app.voxelsLabel_2 = uilabel(app.Panel);
            app.voxelsLabel_2.Text = 'voxels';
            app.SDLabel = uilabel(app.Panel);
            app.SDLabel.Text = 'SD';
            app.smoothingkernel = uieditfield(app.Panel, 'numeric');
            app.smoothingkernel.Value = 0.25;
            app.ThresholdSliderLabel = uilabel(app.Panel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.Panel);
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.MajorTickLabels = {};
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ModefilterLabel = uilabel(app.Panel);
            app.ModefilterLabel.Text = 'Mode filter ';
            app.modeCB = uicheckbox(app.Panel);
            app.modeCB.Text = '';
            app.SizeLabel = uilabel(app.Panel);
            app.SizeLabel.Text = 'Size';
            app.modefiltersize = uieditfield(app.Panel, 'numeric');
            app.modefiltersize.Value = 2;
            app.xbyxvoxelsLabel = uilabel(app.Panel);
            app.xbyxvoxelsLabel.Text = 'x-by-x voxels';
            app.RunclusterButton = uibutton(app.Panel, 'push');
            app.RunclusterButton.ButtonPushedFcn = createCallbackFcn(app, @RunclusterButtonPushed, true);
            app.RunclusterButton.Text = 'Run cluster';
            app.MagnitudeimageLabel = uilabel(app.Panel);
            app.MagnitudeimageLabel.Text = 'Magnitude image';
            app.CompleximageLabel = uilabel(app.Panel);
            app.CompleximageLabel.Text = 'Complex image';
            app.magCB = uicheckbox(app.Panel);
            app.magCB.ValueChangedFcn = createCallbackFcn(app, @magCBValueChanged, true);
            app.magCB.Text = '';
            app.compCB = uicheckbox(app.Panel);
            app.compCB.ValueChangedFcn = createCallbackFcn(app, @compCBValueChanged, true);
            app.compCB.Text = '';
            app.compCB.Value = true;
            app.TabGroup4 = uitabgroup(app.ClusterTab);
            app.ClusterTab_2 = uitab(app.TabGroup4);
            app.ClusterTab_2.Title = 'Cluster';
            app.UIAxes_cluster = uiaxes(app.ClusterTab_2);
            app.FilteredclusterTab = uitab(app.TabGroup4);
            app.FilteredclusterTab.Title = 'Filtered cluster';
            app.UIAxes_cluster_2 = uiaxes(app.FilteredclusterTab);
            app.ViewclusterTab = uitab(app.TabGroup4);
            app.ViewclusterTab.Title = 'View cluster';
            app.TabGroup8 = uitabgroup(app.ViewclusterTab);
            app.AllTab_2 = uitab(app.TabGroup8);
            app.AllTab_2.Title = 'All';
            app.cluster_option_plot = uidropdown(app.AllTab_2);
            app.cluster_option_plot.Items = {'-'};
            app.cluster_option_plot.Editable = 'on';
            app.cluster_option_plot.ValueChangedFcn = createCallbackFcn(app, @cluster_option_plotValueChanged, true);
            app.cluster_option_plot.Value = '-';
            app.UpdateButton_2 = uibutton(app.AllTab_2, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.figname_2 = uieditfield(app.AllTab_2, 'text');
            app.figname_2.Enable = 'off';
            app.figname_2.Value = 'cluster.fig';
            app.savefig_CB_2 = uicheckbox(app.AllTab_2);
            app.savefig_CB_2.ValueChangedFcn = createCallbackFcn(app, @savefig_CB_2ValueChanged, true);
            app.savefig_CB_2.Text = '';
            app.SavefigureLabel_2 = uilabel(app.AllTab_2);
            app.SavefigureLabel_2.Text = 'Save figure';
            app.DefinedTab_2 = uitab(app.TabGroup8);
            app.DefinedTab_2.Title = 'Defined';
            app.cluster_option_plot_2 = uidropdown(app.DefinedTab_2);
            app.cluster_option_plot_2.Items = {'-'};
            app.cluster_option_plot_2.Editable = 'on';
            app.cluster_option_plot_2.ValueChangedFcn = createCallbackFcn(app, @cluster_option_plot_2ValueChanged, true);
            app.cluster_option_plot_2.Value = '-';
            app.UpdateButton_3 = uibutton(app.DefinedTab_2, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Text = 'Update';
            app.figname_3 = uieditfield(app.DefinedTab_2, 'text');
            app.figname_3.Enable = 'off';
            app.figname_3.Value = 'cluster.fig';
            app.savefig_CB_3 = uicheckbox(app.DefinedTab_2);
            app.savefig_CB_3.ValueChangedFcn = createCallbackFcn(app, @savefig_CB_3ValueChanged, true);
            app.savefig_CB_3.Text = '';
            app.SavefigureLabel_3 = uilabel(app.DefinedTab_2);
            app.SavefigureLabel_3.Text = 'Save figure';
            app.UIAxes_cluster_3 = uiaxes(app.ViewclusterTab);
            app.NMRDFittingPanel = uipanel(app.ClusterTab);
            app.NMRDFittingPanel.Title = 'NMRD Fitting';
            app.TabGroup6 = uitabgroup(app.NMRDFittingPanel);
            app.TabGroup6.SelectionChangedFcn = createCallbackFcn(app, @TabGroup6SelectionChanged, true);
            app.DefineclustersTab = uitab(app.TabGroup6);
            app.DefineclustersTab.SizeChangedFcn = createCallbackFcn(app, @DefineclustersTabSizeChanged, true);
            app.DefineclustersTab.Title = 'Define clusters';
            app.Panel_7 = uipanel(app.DefineclustersTab);
            app.Label = uilabel(app.Panel_7);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.Panel_7);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.Panel_7);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.Panel_7);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.Panel_7);
            app.Label_5.Text = '5';
            app.NameLabel = uilabel(app.Panel_7);
            app.NameLabel.Text = 'Name';
            app.ClusterIDLabel = uilabel(app.Panel_7);
            app.ClusterIDLabel.Text = 'Cluster ID';
            app.FitLabel = uilabel(app.Panel_7);
            app.FitLabel.Text = 'Fit';
            app.Cluster1_name = uieditfield(app.Panel_7, 'text');
            app.Cluster1_name.Value = 'Tissue1';
            app.Cluster2_name = uieditfield(app.Panel_7, 'text');
            app.Cluster2_name.Value = 'Tissue2';
            app.Cluster3_name = uieditfield(app.Panel_7, 'text');
            app.Cluster3_name.Value = 'Tissue3';
            app.Cluster4_name = uieditfield(app.Panel_7, 'text');
            app.Cluster4_name.Value = 'Tissue4';
            app.Cluster5_name = uieditfield(app.Panel_7, 'text');
            app.Cluster5_name.Value = 'Tissue5';
            app.CB1 = uicheckbox(app.Panel_7);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = '';
            app.CB1.Value = true;
            app.Cluster1 = uieditfield(app.Panel_7, 'text');
            app.Cluster1.Value = '[23, 21]';
            app.CB2 = uicheckbox(app.Panel_7);
            app.CB2.ValueChangedFcn = createCallbackFcn(app, @CB2ValueChanged, true);
            app.CB2.Text = '';
            app.Cluster2 = uieditfield(app.Panel_7, 'text');
            app.Cluster2.Value = '[153]';
            app.CB3 = uicheckbox(app.Panel_7);
            app.CB3.ValueChangedFcn = createCallbackFcn(app, @CB3ValueChanged, true);
            app.CB3.Text = '';
            app.Cluster3 = uieditfield(app.Panel_7, 'text');
            app.Cluster3.Value = '[,]';
            app.CB4 = uicheckbox(app.Panel_7);
            app.CB4.ValueChangedFcn = createCallbackFcn(app, @CB4ValueChanged, true);
            app.CB4.Text = '';
            app.Cluster4 = uieditfield(app.Panel_7, 'text');
            app.Cluster4.Value = '[,]';
            app.CB5 = uicheckbox(app.Panel_7);
            app.CB5.ValueChangedFcn = createCallbackFcn(app, @CB5ValueChanged, true);
            app.CB5.Text = '';
            app.Cluster5 = uieditfield(app.Panel_7, 'text');
            app.Cluster5.Value = '[,]';
            app.SearchclustersButton = uibutton(app.Panel_7, 'push');
            app.SearchclustersButton.ButtonPushedFcn = createCallbackFcn(app, @SearchclustersButtonPushed, true);
            app.SearchclustersButton.Text = 'Search clusters';
            app.IndxLabel = uilabel(app.Panel_7);
            app.IndxLabel.Text = 'Indx';
            app.c_value = uieditfield(app.Panel_7, 'numeric');
            app.c_value.Editable = 'off';
            app.median_mean_2 = uiswitch(app.Panel_7, 'slider');
            app.median_mean_2.Items = {'Median', 'Mean'};
            app.median_mean_2.ItemsData = {'1', '0'};
            app.median_mean_2.Value = '1';
            app.ClusterIDLabel_2 = uilabel(app.Panel_7);
            app.ClusterIDLabel_2.Text = 'Cluster ID';
            app.ROIaverageasLabel_2 = uilabel(app.Panel_7);
            app.ROIaverageasLabel_2.Text = 'ROI average as';
            app.RunfitButton = uibutton(app.Panel_7, 'push');
            app.RunfitButton.ButtonPushedFcn = createCallbackFcn(app, @RunfitButtonPushed, true);
            app.RunfitButton.Text = 'Run fit';
            app.RunningLampLabel = uilabel(app.Panel_7);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.Panel_7);
            app.RunningLamp.Enable = 'off';
            app.AllclustersTab = uitab(app.TabGroup6);
            app.AllclustersTab.SizeChangedFcn = createCallbackFcn(app, @AllclustersTabSizeChanged, true);
            app.AllclustersTab.Title = 'All clusters';
            app.MinimumclustersizeLabel = uilabel(app.AllclustersTab);
            app.MinimumclustersizeLabel.Text = 'Minimum cluster size';
            app.minclustersize = uieditfield(app.AllclustersTab, 'numeric');
            app.minclustersize.Value = 5;
            app.voxelsLabel = uilabel(app.AllclustersTab);
            app.voxelsLabel.Text = 'voxels';
            app.RunningLamp_2Label = uilabel(app.AllclustersTab);
            app.RunningLamp_2Label.Text = 'Running';
            app.RunningLamp_2 = uilamp(app.AllclustersTab);
            app.RunningLamp_2.Enable = 'off';
            app.fitallclusters = uibutton(app.AllclustersTab, 'push');
            app.fitallclusters.ButtonPushedFcn = createCallbackFcn(app, @fitallclustersPushed, true);
            app.fitallclusters.Text = 'Run fit';
            app.median_mean_3 = uiswitch(app.AllclustersTab, 'slider');
            app.median_mean_3.Items = {'Median', 'Mean'};
            app.median_mean_3.ItemsData = {'1', '0'};
            app.median_mean_3.Value = '1';
            app.ROIaverageasLabel_3 = uilabel(app.AllclustersTab);
            app.ROIaverageasLabel_3.Text = 'ROI average as';
            app.Label_11 = uilabel(app.NMRDFittingPanel);
            app.Label_11.Text = '';
            app.Panel_21 = uipanel(app.NMRDFittingPanel);
            app.UITable_4 = uitable(app.Panel_21);
            app.UITable_4.ColumnName = {'Field (mT)'; 'R1 Guess (1/s)'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = [false true];
            app.R1initialguessLabel_3 = uilabel(app.Panel_21);
            app.R1initialguessLabel_3.Text = 'R1 initial guess';
            app.noise_3 = uieditfield(app.Panel_21, 'numeric');
            app.noise_3.Value = 0.1;
            app.NoisefactorLabel_3 = uilabel(app.Panel_21);
            app.NoisefactorLabel_3.Text = 'Noise factor';
            app.ofsignalLabel_3 = uilabel(app.Panel_21);
            app.ofsignalLabel_3.Text = '% of signal';
            app.Label_28 = uilabel(app.Panel_21);
            app.Label_28.Text = '1';
            app.Label_29 = uilabel(app.Panel_21);
            app.Label_29.Text = '2';
            app.Label_30 = uilabel(app.Panel_21);
            app.Label_30.Text = '3';
            app.Label_31 = uilabel(app.Panel_21);
            app.Label_31.Text = '4';
            app.Label_32 = uilabel(app.Panel_21);
            app.Label_32.Text = '5';
            app.IndxLabel_6 = uilabel(app.Panel_21);
            app.IndxLabel_6.Text = 'Indx';
            app.GuessLabel_2 = uilabel(app.Panel_21);
            app.GuessLabel_2.Text = 'Guess';
            app.Set1_2 = uibutton(app.Panel_21, 'push');
            app.Set1_2.ButtonPushedFcn = createCallbackFcn(app, @Set1_2ButtonPushed, true);
            app.Set1_2.Text = 'Set';
            app.Set2_2 = uibutton(app.Panel_21, 'push');
            app.Set2_2.ButtonPushedFcn = createCallbackFcn(app, @Set2_2ButtonPushed, true);
            app.Set2_2.Enable = 'off';
            app.Set2_2.Text = 'Set';
            app.Set3_2 = uibutton(app.Panel_21, 'push');
            app.Set3_2.ButtonPushedFcn = createCallbackFcn(app, @Set3_2ButtonPushed, true);
            app.Set3_2.Enable = 'off';
            app.Set3_2.Text = 'Set';
            app.Set4_2 = uibutton(app.Panel_21, 'push');
            app.Set4_2.ButtonPushedFcn = createCallbackFcn(app, @Set4_2ButtonPushed, true);
            app.Set4_2.Enable = 'off';
            app.Set4_2.Text = 'Set';
            app.Set5_2 = uibutton(app.Panel_21, 'push');
            app.Set5_2.ButtonPushedFcn = createCallbackFcn(app, @Set5_2ButtonPushed, true);
            app.Set5_2.Enable = 'off';
            app.Set5_2.Text = 'Set';
            app.Lamp1_2 = uilamp(app.Panel_21);
            app.Lamp1_2.Enable = 'off';
            app.Lamp2_2 = uilamp(app.Panel_21);
            app.Lamp2_2.Enable = 'off';
            app.Lamp3_2 = uilamp(app.Panel_21);
            app.Lamp3_2.Enable = 'off';
            app.Lamp4_2 = uilamp(app.Panel_21);
            app.Lamp4_2.Enable = 'off';
            app.Lamp5_2 = uilamp(app.Panel_21);
            app.Lamp5_2.Enable = 'off';
            app.ResetButton_2 = uibutton(app.Panel_21, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.TabGroup5 = uitabgroup(app.ClusterTab);
            app.NMRDTab = uitab(app.TabGroup5);
            app.NMRDTab.Title = 'NMRD';
            app.UIAxes_7 = uiaxes(app.NMRDTab);
            app.UIAxes_7.XTick = [0 0.5 1];
            app.TabGroup7 = uitabgroup(app.NMRDTab);
            app.AllTab = uitab(app.TabGroup7);
            app.AllTab.Title = 'All';
            app.MultipleclustersLabel = uilabel(app.AllTab);
            app.MultipleclustersLabel.Text = 'Multiple clusters';
            app.multipleclusters_V = uieditfield(app.AllTab, 'text');
            app.multipleclusters_V.Value = '-';
            app.multipleclusters_CB = uicheckbox(app.AllTab);
            app.multipleclusters_CB.ValueChangedFcn = createCallbackFcn(app, @multipleclusters_CBValueChanged, true);
            app.multipleclusters_CB.Text = '';
            app.cluster_option = uidropdown(app.AllTab);
            app.cluster_option.Items = {'-'};
            app.cluster_option.Editable = 'on';
            app.cluster_option.Value = '-';
            app.singleclusters_CB = uicheckbox(app.AllTab);
            app.singleclusters_CB.ValueChangedFcn = createCallbackFcn(app, @singleclusters_CBValueChanged, true);
            app.singleclusters_CB.Text = '';
            app.singleclusters_CB.Value = true;
            app.SingeclusterLabel = uilabel(app.AllTab);
            app.SingeclusterLabel.Text = 'Singe cluster';
            app.max5Label = uilabel(app.AllTab);
            app.max5Label.Text = '(max. 5)';
            app.DefinedTab = uitab(app.TabGroup7);
            app.DefinedTab.Title = 'Defined';
            app.IndxLabel_2 = uilabel(app.DefinedTab);
            app.IndxLabel_2.Text = 'Indx';
            app.SelectLabel = uilabel(app.DefinedTab);
            app.SelectLabel.Text = 'Select';
            app.A1 = uicheckbox(app.DefinedTab);
            app.A1.ValueChangedFcn = createCallbackFcn(app, @A1ValueChanged, true);
            app.A1.Text = '';
            app.A2 = uicheckbox(app.DefinedTab);
            app.A2.ValueChangedFcn = createCallbackFcn(app, @A2ValueChanged, true);
            app.A2.Text = '';
            app.A3 = uicheckbox(app.DefinedTab);
            app.A3.ValueChangedFcn = createCallbackFcn(app, @A3ValueChanged, true);
            app.A3.Text = '';
            app.A4 = uicheckbox(app.DefinedTab);
            app.A4.ValueChangedFcn = createCallbackFcn(app, @A4ValueChanged, true);
            app.A4.Text = '';
            app.A5 = uicheckbox(app.DefinedTab);
            app.A5.ValueChangedFcn = createCallbackFcn(app, @A5ValueChanged, true);
            app.A5.Text = '';
            app.Label_6 = uilabel(app.DefinedTab);
            app.Label_6.Text = '1';
            app.Label_7 = uilabel(app.DefinedTab);
            app.Label_7.Text = '2';
            app.Label_8 = uilabel(app.DefinedTab);
            app.Label_8.Text = '3';
            app.Label_9 = uilabel(app.DefinedTab);
            app.Label_9.Text = '4';
            app.Label_10 = uilabel(app.DefinedTab);
            app.Label_10.Text = '5';
            app.LogscalexLabel = uilabel(app.NMRDTab);
            app.LogscalexLabel.Text = 'Log scale x';
            app.LogscaleyLabel = uilabel(app.NMRDTab);
            app.LogscaleyLabel.Text = 'Log scale y';
            app.xlog_CB = uicheckbox(app.NMRDTab);
            app.xlog_CB.Text = '';
            app.ylog_CB = uicheckbox(app.NMRDTab);
            app.ylog_CB.Text = '';
            app.xminLabel = uilabel(app.NMRDTab);
            app.xminLabel.Text = 'x min';
            app.xmaxLabel = uilabel(app.NMRDTab);
            app.xmaxLabel.Text = 'x max';
            app.yminLabel = uilabel(app.NMRDTab);
            app.yminLabel.Text = 'y min';
            app.ymaxLabel = uilabel(app.NMRDTab);
            app.ymaxLabel.Text = 'y max';
            app.ErrorbarsLabel = uilabel(app.NMRDTab);
            app.ErrorbarsLabel.Text = 'Error bars';
            app.errorbar_CB = uicheckbox(app.NMRDTab);
            app.errorbar_CB.Text = '';
            app.errorbar_CB.Value = true;
            app.AutoscaleLabel = uilabel(app.NMRDTab);
            app.AutoscaleLabel.Text = 'Auto scale';
            app.autoscale_CB = uicheckbox(app.NMRDTab);
            app.autoscale_CB.ValueChangedFcn = createCallbackFcn(app, @autoscale_CBValueChanged, true);
            app.autoscale_CB.Text = '';
            app.autoscale_CB.Value = true;
            app.xmin = uieditfield(app.NMRDTab, 'numeric');
            app.xmin.Enable = 'off';
            app.xmax = uieditfield(app.NMRDTab, 'numeric');
            app.xmax.Enable = 'off';
            app.xmax.Value = 250;
            app.ymin = uieditfield(app.NMRDTab, 'numeric');
            app.ymin.Enable = 'off';
            app.ymax = uieditfield(app.NMRDTab, 'numeric');
            app.ymax.Enable = 'off';
            app.ymax.Value = 20;
            app.UpdateButton = uibutton(app.NMRDTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.figname = uieditfield(app.NMRDTab, 'text');
            app.figname.Enable = 'off';
            app.figname.Value = 'NMRD.fig';
            app.mTLabel = uilabel(app.NMRDTab);
            app.mTLabel.Text = 'mT';
            app.sLabel = uilabel(app.NMRDTab);
            app.sLabel.Text = '1/s';
            app.exportfigureonlyLabel = uilabel(app.NMRDTab);
            app.exportfigureonlyLabel.Text = '(export figure only)';
            app.SavefigureLabel = uilabel(app.NMRDTab);
            app.SavefigureLabel.Text = 'Save figure';
            app.savefig_CB = uicheckbox(app.NMRDTab);
            app.savefig_CB.ValueChangedFcn = createCallbackFcn(app, @savefig_CBValueChanged, true);
            app.savefig_CB.Text = '';
            app.FitTab = uitab(app.TabGroup5);
            app.FitTab.Title = 'Fit';
            app.allfit = uidropdown(app.FitTab);
            app.allfit.Items = {'-'};
            app.allfit.Editable = 'on';
            app.allfit.Value = '-';
            app.definefit = uidropdown(app.FitTab);
            app.definefit.Items = {'-'};
            app.definefit.Editable = 'on';
            app.definefit.ValueChangedFcn = createCallbackFcn(app, @definefitValueChanged, true);
            app.definefit.Value = '-';
            app.xminLabel_2 = uilabel(app.FitTab);
            app.xminLabel_2.Text = 'x min';
            app.xmaxLabel_2 = uilabel(app.FitTab);
            app.xmaxLabel_2.Text = 'x max';
            app.yminLabel_2 = uilabel(app.FitTab);
            app.yminLabel_2.Text = 'y min';
            app.ymaxLabel_2 = uilabel(app.FitTab);
            app.ymaxLabel_2.Text = 'y max';
            app.AutoscaleLabel_2 = uilabel(app.FitTab);
            app.AutoscaleLabel_2.Text = 'Auto scale';
            app.autoscale_CB_2 = uicheckbox(app.FitTab);
            app.autoscale_CB_2.ValueChangedFcn = createCallbackFcn(app, @autoscale_CB_2ValueChanged, true);
            app.autoscale_CB_2.Text = '';
            app.autoscale_CB_2.Value = true;
            app.xmin_2 = uieditfield(app.FitTab, 'numeric');
            app.xmin_2.Enable = 'off';
            app.xmax_2 = uieditfield(app.FitTab, 'numeric');
            app.xmax_2.Enable = 'off';
            app.xmax_2.Value = 4;
            app.ymin_2 = uieditfield(app.FitTab, 'numeric');
            app.ymin_2.Enable = 'off';
            app.ymax_2 = uieditfield(app.FitTab, 'numeric');
            app.ymax_2.Enable = 'off';
            app.ymax_2.Value = 10;
            app.sLabel_3 = uilabel(app.FitTab);
            app.sLabel_3.Text = 's';
            app.UpdateButton_4 = uibutton(app.FitTab, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Text = 'Update';
            app.figname_4 = uieditfield(app.FitTab, 'text');
            app.figname_4.Enable = 'off';
            app.figname_4.Value = 'fit.fig';
            app.SavefigureLabel_4 = uilabel(app.FitTab);
            app.SavefigureLabel_4.Text = 'Save figure';
            app.savefig_CB_4 = uicheckbox(app.FitTab);
            app.savefig_CB_4.ValueChangedFcn = createCallbackFcn(app, @savefig_CB_4ValueChanged, true);
            app.savefig_CB_4.Text = '';
            app.ClusterLabel = uilabel(app.FitTab);
            app.ClusterLabel.Text = 'Cluster';
            app.allfit_CB = uicheckbox(app.FitTab);
            app.allfit_CB.ValueChangedFcn = createCallbackFcn(app, @allfit_CBValueChanged, true);
            app.allfit_CB.Text = '';
            app.allfit_CB.Value = true;
            app.definefit_CB = uicheckbox(app.FitTab);
            app.definefit_CB.ValueChangedFcn = createCallbackFcn(app, @definefit_CBValueChanged, true);
            app.definefit_CB.Text = '';
            app.rsquaredLabel = uilabel(app.FitTab);
            app.rsquaredLabel.Text = 'r squared';
            app.r2 = uieditfield(app.FitTab, 'numeric');
            app.r2.Editable = 'off';
            app.Panel_8 = uipanel(app.FitTab);
            app.Panel_9 = uipanel(app.ClusterTab);
            app.AllclusterfitLabel = uilabel(app.Panel_9);
            app.AllclusterfitLabel.Text = 'All cluster fit';
            app.all_CB = uicheckbox(app.Panel_9);
            app.all_CB.Text = '';
            app.def_CB = uicheckbox(app.Panel_9);
            app.def_CB.Text = '';
            app.DefinedclusterfitLabel = uilabel(app.Panel_9);
            app.DefinedclusterfitLabel.Text = 'Defined cluster fit';
            app.FoldernameLabel = uilabel(app.Panel_9);
            app.FoldernameLabel.Text = 'Folder name';
            app.foldername = uieditfield(app.Panel_9, 'text');
            app.foldername.Value = 'Cluster fit';
            app.excelname = uieditfield(app.Panel_9, 'text');
            app.excelname.Value = 'Cluster.xlsx';
            app.ExcelfilenameLabel = uilabel(app.Panel_9);
            app.ExcelfilenameLabel.Text = 'Excel file name';
            app.fitallclusters_2 = uibutton(app.Panel_9, 'push');
            app.fitallclusters_2.ButtonPushedFcn = createCallbackFcn(app, @fitallclusters_2ButtonPushed, true);
            app.fitallclusters_2.Text = 'Save ';
            app.Panel_15 = uipanel(app.Panel_14);
            app.LoadfileButton = uibutton(app.Panel_15, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.matfileexportedfromImageProcessingLabel = uilabel(app.Panel_15);
            app.matfileexportedfromImageProcessingLabel.Text = '.mat file exported from ImageProcessing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFCAnalysis_V3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end